classdef C2K_SessionAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        LoadTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        RemoveFromTable                 matlab.ui.control.Button
        AddToTable                      matlab.ui.control.Button
        PatientTable                    matlab.ui.control.Table
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        SkipToEditField                 matlab.ui.control.EditField
        SkipToEditFieldLabel            matlab.ui.control.Label
        PlayButton                      matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.Button
        AudioPreviewAxes                matlab.ui.control.UIAxes
        GridLayoutPolar                 matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        LoadFolderButton                matlab.ui.control.Button
        FolderSelectionExampleLabel     matlab.ui.control.Label
        DirExampleImage                 matlab.ui.control.Image
        GridLayout4                     matlab.ui.container.GridLayout
        AnalyseAllButton                matlab.ui.control.StateButton
        AnalyseSpeakerDiarButton        matlab.ui.control.StateButton
        AnalyseSMButton                 matlab.ui.control.Button
        C2KAnalyserLabel                matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        GridOverview                    matlab.ui.container.GridLayout
        StatsPanel                      matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        StatsTable                      matlab.ui.control.Table
        GridLayout8                     matlab.ui.container.GridLayout
        ExportResultsButton             matlab.ui.control.Button
        UpdateStatsButton               matlab.ui.control.Button
        StressTab                       matlab.ui.container.Tab
        GridStressAnal                  matlab.ui.container.GridLayout
        StressPanel                     matlab.ui.container.Panel
        GridLayout_StressDD             matlab.ui.container.GridLayout
        SelectGraph_StressDD            matlab.ui.control.DropDown
        SelectGraphLabel_2              matlab.ui.control.Label
        MovementTab                     matlab.ui.container.Tab
        GridMovementAnal                matlab.ui.container.GridLayout
        MovementPanel                   matlab.ui.container.Panel
        GridLayout_MoveDD               matlab.ui.container.GridLayout
        SelectGraph_MoveDD              matlab.ui.control.DropDown
        SelectGraphLabel                matlab.ui.control.Label
        SpeakerDiarTab                  matlab.ui.container.Tab
        GridSpeakerAnal                 matlab.ui.container.GridLayout
        SpeakD_DispPanel                matlab.ui.container.Panel
        GridSpeakDButtons               matlab.ui.container.GridLayout
        SkipToEditField_SpeakD          matlab.ui.control.EditField
        SkipToLabel                     matlab.ui.control.Label
        StopButton_SpeakD               matlab.ui.control.Button
        PlayButton_SpeakD               matlab.ui.control.StateButton
        GridLayout_SpeakerDD            matlab.ui.container.GridLayout
        CutOutSilenceFromAudioCheckBox  matlab.ui.control.CheckBox
        SelectPat_SpeakerDD             matlab.ui.control.DropDown
        SelectGraphLabel_3              matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Audio Parameters:
        aInfo               % Audio Info [Struct]
        audioDS             % Audio Datastore Object
        audioIn             % Audio Data
        audioFs             % Audio Sampling Rate
        audioPlayer         % Audio Player
        audioStartFrom_Pre  % Audio Start From Time
        audioST             % Audio Starting Time - [datetime]
        audioT_Pre          % Audio Time Vector - Preview
        audioT_Comp         % Audio Time Vector - Complete
        audioPro            % Audio Data - Processed via SpeakerDiar

        % Sensor (Polar) Parameters:
        sensorDataTab % Sensor Data - [table]

        % Stress Parameters:
        HRV                 % Stress - Processed HRV data [cell]
        HRV_Time            % Stress - HRV timeline [cell]
        StressYN            % Stress - Stress Occured YN [cell]
        StressTime          % Stress - Stress Timesegments [cell]

        % Movement Parameters:
        XYZ_Acc             % Movement - Processed XYZ data [cell]
        MoveSummed_Acc      % Movement - Summed Bodymovement [cell]
        MoveTimeSegs_Acc    % Movement - Timesegments [cell]
        MoveYN_Acc          % Movement - Movement Occured YN [cell]

        % Speaker Diarisation Parameters:
        speakMsk                % SpeakD - Speaker Mask (Segments) [matrix - n x 3][signalMask]
        speakSegments           % SpeakD - Speaker Segments [matrix - n x 3]
        speakerDPlayer          % SpeakD - Speaker Diarisation AudioPlayer
        audioStartFrom_Speak    % SpeakD - Audio Start From

        % Stat Paremeters:
        stressObs           % Number of stress observations per patient
        stressMinBeats      % Min bpm per patient
        stressMaxBeats      % Max bpm per patient
        stressAvgBeats      % Avg bpm per patient
        moveObs             % Movement count per patient
        moveMaxG            % Max Movement G's per patient
        moveAvgG            % Avg Movement G's per patient
        speakTurns           % Number of spoken turns per patient
        speakDur            % Total Speech Duration per patient
        speakLongestPhrase  % Longest spoken phrase per patient
        speakLongestTurn    % Longest spoken turn per patient

        % Display Parameters:
        overviewPlots
        stressPlots         % Stress Plots - Axial [cell]
        movePlots           % Movement Plots - Axial [cell]
        speakTile           % SpeakD - tileLayout for speaker diarisation panel
%         speakerPlots        % SpeakerDiarisation Plots - Axial

        % Application Status Parameters:
        loadedStatus logical        % Data Loaded
        aPreStoppedStatus logical   % Audio (Preview) in Initial Position
        aSpeakStoppedStatus logical % Audio (SpeakDiar) in Initial Position
        stressAnalysed logical      % Stress Analysed
        moveAnalysed logical        % Movement Analysed
        speakerAnalysed logical     % Speaker Diarisation Analysed
%         pool                        % Application's Parpool
%         futCallbacks                % Future objects for callback functions
        progressFig                 % Progress UIFigure
    end
    
    properties (Access = public)
        % Application Status Parameters: (To be shared with external
        % functions)
        closeReq logical       % Close App Request
        funcExecuting double   % Function executing status [array]
    end
    
    methods (Access = private)

        function loadFunc(app,selPath)
            %% Clear Previous Analysis Session:
            if app.loadedStatus
                % Clear app paremters:
                app.aPreStoppedStatus = 1;
                app.stressAnalysed = 0;
                app.moveAnalysed = 0;
                app.speakerAnalysed = 0;
                app.funcExecuting = zeros(5,1);
                app.overviewPlots = [];
                app.stressPlots = [];
                app.movePlots = [];
                app.speakTile = [];
                % Recreate Audio:
                audioStopped(app);
                app.PlayButton.Text = 'Play';
                app.PlayButton_SpeakD.Text = 'Play';
                app.audioPlayer = [];
                app.speakerDPlayer = [];
                app.audioStartFrom_Pre = 1;
                app.audioStartFrom_Speak = 1;
                app.SkipToEditField.Value = "hh:mm:ss";
                app.SkipToEditField_SpeakD.Value = "hh:mm:ss";
                % Recreate stress panel:
                delete(app.StressPanel);
                app.StressPanel = uipanel(app.GridStressAnal);
                app.StressPanel.Layout.Row = 2;
                app.StressPanel.Layout.Column = 1;
                app.SelectGraph_StressDD.Value = app.SelectGraph_StressDD.Items{1};
                % Recreate movement panel:
                delete(app.MovementPanel);
                app.MovementPanel = uipanel(app.GridMovementAnal);
                app.MovementPanel.Layout.Row = 2;
                app.MovementPanel.Layout.Column = 1;
                app.SelectGraph_MoveDD.Value = app.SelectGraph_MoveDD.Items{1};
                % Recreate speakerDiar panel:
                delete(app.SpeakD_DispPanel);
                app.SpeakD_DispPanel = uipanel(app.GridSpeakerAnal);
                app.SpeakD_DispPanel.Layout.Row = 2;
                app.SpeakD_DispPanel.Layout.Column = 1;
                app.SelectPat_SpeakerDD.Items = {'All'};
                app.SelectPat_SpeakerDD.ItemsData = 0;
                app.CutOutSilenceFromAudioCheckBox.Value = 0;
                % Clear SpeakerDiar Parameters:
                app.speakSegments = [];
                app.speakMsk = [];
            end
            %% Get Session Files:
            fileList = dir(selPath);
            fileList = {fileList.name}';
            fileList = string(fileList);
            fileList(~contains(fileList,lettersPattern)) = [];
            fileName = selPath+"/"+fileList;

            %% Get Audio Files:
            % pat = (".wav"|".mp3"|".flac"|".mp4"|".ma4"|".au");
            pat = (".wav"|".flac");
            a_fileList = fileName(contains(fileName,pat));
            if size(a_fileList,1)~=1
                uialert(app.UIFigure,"Incorrect number of audio files "+...
                    "present in folder.","Number of Audio Files Present Incorrect");
            else
                % Create audioPlayer:
                app.aInfo = audioinfo(a_fileList);
                if app.aInfo.SampleRate ~= 16e3
                    errordlg("Audio was recorded at a different sample rate. Should be 16 kHz");
                    return;
                end
                app.audioFs = app.aInfo.SampleRate;
                % Read first 5minutes of the audio as a preview:
                if app.aInfo.TotalSamples <= 5*60*app.aInfo.SampleRate
                    [app.audioIn,~] = audioread(a_fileList);
                else
                    [app.audioIn,~] = audioread(a_fileList,[1,5*60*app.aInfo.SampleRate]);
                end
                app.audioDS = audioDatastore(a_fileList); % Create Audio Datastore
                app.audioPlayer = audioplayer(app.audioIn,app.audioFs); % Create Audio Player
                app.audioPlayer.StopFcn = @(~,~)audioStopped(app);
                app.audioPlayer.StartFcn = @(~,~)audioStarted(app,1);
            end

            %% Get Audio Starting Time:
            pat = (".mat");
            aST_fileList = fileName(contains(fileName,pat));
            aST_Idx = zeros(size(aST_fileList));
            idxCnt = 1;
            % Determine which file is the AudioST:
            for ii=1:size(aST_fileList,1)
                matObj = matfile(aST_fileList(ii));
                if whos(matObj).class == "datetime"
                    aST_Idx(idxCnt) = ii;
                    idxCnt = idxCnt+1;
                else
                    aST_Idx(idxCnt) = [];
                end
            end
            if size(aST_Idx,1)~=1
                uialert(app.UIFigure,"Incorrect number of datetime files "+...
                    "present in folder.","Number of Audio Start Time "+...
                    "Files Present Incorrect");
            else
                % Get Audio Starting Time:
                tempAST = load(aST_fileList(aST_Idx(1)));
                app.audioST = tempAST.audioSTime;

                % Display Audio on Axis:
                t = ((0:size(app.audioIn,1)-1)/app.audioFs)';
                app.audioT_Pre = seconds(t)+app.audioST;
                plot(app.AudioPreviewAxes,app.audioT_Pre,app.audioIn,'Color','black');
                app.audioPlayer.TimerFcn = @(~,~)dispAudioPlayback(app,1);
                app.audioPlayer.TimerPeriod = 0.3;

                % Create Complete Audio Time Range:
                t = ((0:app.aInfo.TotalSamples-1)/app.audioFs)';
                app.audioT_Comp = seconds(t)+app.audioST;
            end

            %% Get Sensor Data:
            pat = (".mat");
            sensData_fileList = fileName(contains(fileName,pat));
            sensData_Idx = zeros(size(sensData_fileList));
            idxCnt = 1;
            % Determine which file is the Sensor Data:
            for ii=1:size(sensData_fileList,1)
                matObj = matfile(sensData_fileList(ii));
                if whos(matObj).class == "table"
                    sensData_Idx(idxCnt) = ii;
                    idxCnt = idxCnt+1;
                else
                    sensData_Idx(idxCnt) = [];
                end
            end
            if size(sensData_Idx,1)~=1
                uialert(app.UIFigure,"Incorrect number of sensor data files "+...
                    "present in folder.","Number of Sensor Data "+...
                    "Files Present Incorrect");
            else
                % Get Sensor Data:
                tempSensDat = load(sensData_fileList(sensData_Idx(1)));
                app.sensorDataTab = tempSensDat.dataTab;
            end

            %% Get Patient Info
            pat = (".csv");
            patInfo_fileList = fileName(contains(fileName,pat));

            if size(patInfo_fileList,1) ~= 1
                uialert(app.UIFigure,"Incorrect number of '.csv' files "+...
                    "present in folder.","Number of Patient Info Files "+...
                    "Present Incorrect");
            else
                % Get Patient Info:
                tempPatInfo = readtable(patInfo_fileList);
                tempPatInfo.SpeakerID = string(((1:size(tempPatInfo,1))+100)');
                app.PatientTable.Data = tempPatInfo(:,{'PatientID','Gender','Age','SpeakerID'});

                % Update patient select DropDowns:
                strArr = strings(size(app.PatientTable.Data,1)+1,1);
                strArr(1,1) = "All";
                strArr(2:end,1) = app.PatientTable.Data.SpeakerID;
                app.SelectPat_SpeakerDD.Items = strArr;
                app.SelectPat_SpeakerDD.ItemsData = (0:size(app.PatientTable.Data,1))';

                % Setup Overview Stats Table:
                updateStatsTable(app);

            end

            %% Create Analysis Axials:
            % Analysis Plot Parameters:
            app.stressPlots = cell(size(app.sensorDataTab,1),1);
            app.movePlots = cell(size(app.sensorDataTab,1),1);
            n_rows = ceil(size(app.movePlots,1)./2);
            n_columns = 2;
            if n_rows<2;n_rows=2;n_columns=1;end
            app.StressPanel.AutoResizeChildren = 'off';
            app.MovementPanel.AutoResizeChildren = 'off';
            pltCnt = 1;
            % Create Stress and Movement Plots:
            for mCnt = 1:size(app.movePlots,1)
                if size(app.movePlots,1)<n_rows*n_columns
                    if pltCnt
                        app.movePlots{mCnt} = subplot(n_rows,n_columns,[1,2],...
                            'Parent',app.MovementPanel);
                        app.stressPlots{mCnt} = subplot(n_rows,n_columns,[1,2],...
                            'Parent',app.StressPanel);
                        pltCnt = 2;
                    else
                        app.movePlots{mCnt} = subplot(n_rows,n_columns,pltCnt,...
                            'Parent',app.MovementPanel);
                        app.stressPlots{mCnt} = subplot(n_rows,n_columns,pltCnt,...
                            'Parent',app.StressPanel);
                    end
                else
                    app.movePlots{mCnt} = subplot(n_rows,n_columns,pltCnt,...
                        'Parent',app.MovementPanel);
                    app.stressPlots{mCnt}= subplot(n_rows,n_columns,pltCnt,...
                        'Parent',app.StressPanel);
                end
                pltCnt= pltCnt+1;
            end

            % Change LoadedStatus:
            app.loadedStatus = 1;   % Data Successfully Loaded
        %EOF
        end


        function audioStopped(app)
            % Audio Stopped:
            %% Parameter Changes:
            if ~app.aSpeakStoppedStatus
                % Speaker AudioPlayer is being stopped:
                app.PlayButton_SpeakD.Value = 0;
                if ~isempty(app.speakerDPlayer)
                    if app.speakerDPlayer.CurrentSample == 1
                        app.PlayButton_SpeakD.Text = 'Play';
                    end
                end
            elseif ~app.aPreStoppedStatus
                % Preview AudioPlayer is being stopped:
                app.PlayButton.Value = 0;
                if ~isempty(app.audioPlayer)
                    if app.audioPlayer.CurrentSample == 1
                        app.PlayButton.Text = 'Play';
                    end
                end
            end
            
            % Enable All Functions again:
            enableFuncs(app);

            app.SelectPat_SpeakerDD.Enable = "on";
            if app.SelectPat_SpeakerDD.Value~=0
                app.CutOutSilenceFromAudioCheckBox.Enable = "on";
            end

            app.funcExecuting(1) = 0;
        %EOF
        end

        function audioStarted(app,SOP)
            % Audio Started:
            %% Parameter Changes:
            switch SOP
                case 0
                    % Speaker-AudioPlayer Started:
                    app.PlayButton_SpeakD.Value = 1;
                    app.PlayButton_SpeakD.Text = 'Pause';
                case 1
                    % Preview-AudioPlayer Started:
                    app.PlayButton.Value = 1;
                    app.PlayButton.Text = 'Pause';
            end

            disableFuncs(app,"Play");

            app.SelectPat_SpeakerDD.Enable = "off";
            app.CutOutSilenceFromAudioCheckBox.Enable = "off";

            app.funcExecuting(1) = 1;

        %EOF
        end

        function dispAudioPlayback(app,SOP)
            if ~app.closeReq
            % Display Audio Playback:
                % Select current player:
                curSample = 0;
                aData = 0;
                Axes = app.AudioPreviewAxes;
            switch SOP
                case 0
                    % Speaker-AudioPlayer Playing:
                    curSample = app.speakerDPlayer.CurrentSample;
                    aData = app.audioT_Comp;
                    Axes = findobj(app.speakTile,'Type','Axes');
                    Axes = Axes(1,:);
                    if app.CutOutSilenceFromAudioCheckBox.Value
                        % Selective Audio:
                        bmsk = binmask(app.speakMsk,numel(aData));
                        soundIdx = find(bmsk(:,app.SelectPat_SpeakerDD.Value));
                        curSample = soundIdx(curSample);
                    end
                case 1
                    % Preview-AudioPlayer Playing:
                    curSample = app.audioPlayer.CurrentSample;
                    aData = app.audioT_Pre;
            end
            numSamples = size(aData,1);
            
            % Create Coordinates:
            chgPts = zeros(2,1);
            if ~isempty(curSample)
                if curSample+2000<numSamples
                    chgPts(1,1) = curSample;
                    chgPts(2,1) = curSample+1900;

                    xCoords = [aData(chgPts(1)) aData(chgPts(2));...
                        aData(chgPts(2)) aData(end);...
                        aData(chgPts(2)) aData(end);...
                        aData(chgPts(1)) aData(chgPts(2))];

                    %% Plot segments:
                    AxesPatch = findobj(Axes,'Type','Patch','Tag','AudioPlayback');
                    if isempty(AxesPatch)
                        % Get yLimits:
                        yLims = ylim(Axes);
                        yCoords = [yLims(1) yLims(1);yLims(1) yLims(1);...
                            yLims(2) yLims(2);yLims(2) yLims(2)];
                        % Create Color Scheme:
                        clrs = zeros(size(xCoords,2),1,3);
                        clrs(1,:,:) = [0 0 1];          % Blue
                        clrs(2,:,:) = [0.8 0.8 0.8];    % Gray
                        % Plot:
                        aPatch = patch(Axes,xCoords,...
                            yCoords,clrs,'FaceAlpha',.35,'LineWidth',1,...
                            'EdgeColor','none','LineWidth',3,'Tag','AudioPlayback');
                        aPatch.Annotation.LegendInformation.IconDisplayStyle = 'off';
                    else
                        AxesPatch.XData = xCoords;
                    end
                end
            end
            else
                app.StopButtonPushed;
            end
            %EOF
        end

        function dispStress(app)
            % Display Stress according to graph selection:
            n = size(app.sensorDataTab,1);
            clrs = {'white','red'};
            graphIdx = find(string(app.SelectGraph_StressDD.Items(:)) == app.SelectGraph_StressDD.Value);
            for ii=1:n
                if graphIdx==1
                    plot(app.stressPlots{ii},app.sensorDataTab.ecgTimestamps{ii},...
                        app.sensorDataTab.ECG{ii},"Color",'black','LineWidth',0.75);
                    ylabel(app.stressPlots{ii},"ECG [mV]");
                elseif graphIdx==2
                    plot(app.stressPlots{ii},app.HRV_Time{ii},...
                        app.HRV{ii},"Color",'black','LineWidth',0.75);
                    ylabel(app.stressPlots{ii},"HRV [ms]");
                end
                xlabel(app.stressPlots{ii},'Time');
                title(app.stressPlots{ii},app.PatientTable.Data.SpeakerID(ii));

                % Display Movement Results:
                app.StressYN{ii}(1) = 1;
                app.StressYN{ii}(end) = 1;
                dispAnalysis(app,app.stressPlots{ii},app.StressYN{ii},...
                    app.StressTime{ii},clrs);
            end
        %EOF
        end

        function dispMovement(app)
            % Display Movement according to graph selection:
            n = size(app.sensorDataTab,1);
            clrs = {'white','green'};
            for ii=1:n
                if strcmpi('Triaxial (XYZ)',app.SelectGraph_MoveDD.Value)
                    plot(app.movePlots{ii},app.sensorDataTab.accTimestamps{ii},...
                        app.XYZ_Acc{ii},'LineWidth',0.75);
%                     legend('X','Y','Z');
                elseif strcmpi('TotalMovement (Summed)',app.SelectGraph_MoveDD.Value)
                    plot(app.movePlots{ii},app.sensorDataTab.accTimestamps{ii},...
                        app.MoveSummed_Acc{ii},"Color",'black','LineWidth',0.75);
                end
                ylabel(app.movePlots{ii},"Acceleration in g's [g=9.81m/s]");
                xlabel(app.movePlots{ii},'Time');
                title(app.movePlots{ii},app.PatientTable.Data.SpeakerID(ii));

                % Display Movement Results:
                dispAnalysis(app,app.movePlots{ii},app.MoveYN_Acc{ii},...
                    app.MoveTimeSegs_Acc{ii},clrs);
            end
            %EOF
        end

        function dispSpeakerDiar(app)
            % Display speaker diarisation results:
            if ~isempty(app.speakSegments)
                % Define plot parameters:
                clrs = zeros(7,1,3);
                C2d = [0 0.5470 1.0000;...
                       1 0 0.0980;...
                       1 1 0;...
                       1 0.15 1;...
                       0 1 0;...
                       0 1 1;...
                       1.0000 0.1158 0.1840]; % Colors = blue,red,yellow,magenta,green,cyan,redish.
                clrs(:,1,:) = C2d;
                fAlpha = 0.3;
                app.speakTile = [];
                app.speakerDPlayer = [];
                reg = app.audioST+seconds(app.speakSegments(:,1:2)/app.audioFs);
                speakers = app.speakSegments(:,3);
                reset(app.audioDS);
                audioData = read(app.audioDS);

                % Plot Selected AudioData:
                if app.SelectPat_SpeakerDD.Value == 0
                    % Display All:
                    app.speakTile = tiledlayout(app.SpeakD_DispPanel,1,1);
                    % Create SpeakerDiar AudioPlayer:
                    app.speakerDPlayer = audioplayer(audioData,app.audioFs); 
                else
                    % Display Selected Patient:
                    app.speakTile = tiledlayout(app.SpeakD_DispPanel,2,1);
                end
                % Display Speaker Diarisation Results:
                ax1 = nexttile(app.speakTile);
                plot(ax1,app.audioT_Comp,audioData,'Color','black');
                axis(ax1,'tight');
                ylabel(ax1,'Amplitude');
                xlabel(ax1,'Time');
                title(ax1,"Speaker: All");
                YLims = ylim(ax1);
                tempY = [YLims(1) YLims(1) YLims(2) YLims(2)];
                % Plot patches:
                for ii=1:size(unique(speakers),1)
                    n = size(speakers(speakers==ii),1);
                    % Create x-coordinates:
%                     x = NaT(4,n);
                    x = [reg(speakers==ii,1) reg(speakers==ii,2) reg(speakers==ii,2) reg(speakers==ii,1)]';
                        % Create y-coordinates:
                    y = ones(4,n);
                    y = y.*tempY';
                        % Create color-vector:
%                     pats = app.SelectPat_SpeakerDD.ItemsData(2:end);
%                     cIdx = speakers(speakers==ii);
%                     [~,cIdx] = ismember(speakers,pats);
                        % Plot Results:
                    p = patch(ax1,x,y,clrs(ii,:,:),'FaceAlpha',fAlpha);
                end
%                 p.Annotation.LegendInformation.IconDisplayStyle = 'off';
                if size(app.PatientTable.Data,1)==size(unique(speakers),1)
                    legend(ax1,'Audio',app.SelectPat_SpeakerDD.Items{unique(speakers)+1});
                elseif size(app.PatientTable.Data,1)+1==size(unique(speakers),1)
                    % Add Physician to Table:
                    temp = [{"-"},{"-"},{"-"},{"Phys"}];
                    temp = cell2table(temp);
                    temp.Properties.VariableNames = app.PatientTable.Data.Properties.VariableNames;
                    app.PatientTable.Data = [app.PatientTable.Data;temp];
                    legend(ax1,'Audio',app.SelectPat_SpeakerDD.Items{unique(speakers)+1});
                else
                    uialert(app.UIFigure,"Number of Speakers found do not match "+...
                        "the number of entries in PatientTable - #ofSpeakersFound: "+...
                        sprintf("%d",size(unique(speakers),1))+...
                        sprintf("\nPossible Fixes: Add/Remove Entries from PatientTable; ")+...
                        "Re-run SpeakerDiarisation","Missmatch between "+...
                        "speakers found and number of patient entries");
                    return;
                end
                
                if app.speakTile.GridSize(1,1)>1
                    % Isolate Speaker:
                    speakerToInspect = app.SelectPat_SpeakerDD.Value;
%                     speakerToInspect = strcmpi(app.speakMsk.Categories,app.SelectPat_SpeakerDD.Value);
                    bmsk = binmask(app.speakMsk,numel(app.audioT_Comp));
                    ax2 = nexttile(app.speakTile);
                    plot(ax2,app.audioT_Comp,audioData.*bmsk(:,speakerToInspect),'Color','black');
                    axis(ax1,'tight');
                    xlabel(ax2,'Time')
                    ylabel(ax2,'Amplitude')
                    title(ax2,"Speaker: "+app.SelectPat_SpeakerDD.Items{app.SelectPat_SpeakerDD.Value+1});
                    % Create Isolated SpeakerDiar AudioPlayer:
%                     audioToPlay = audioData;
                    if app.CutOutSilenceFromAudioCheckBox.Value
                        audioData(~bmsk(:,speakerToInspect)) = [];
                    end
                    app.speakerDPlayer = audioplayer(audioData,app.audioFs); 
                end

                % Create SpeakerDiar AudioPlayer Callbacks:
                app.speakerDPlayer.StartFcn = @(~,~)audioStarted(app,0);
                app.speakerDPlayer.StopFcn = @(~,~)audioStopped(app);
                app.speakerDPlayer.TimerFcn = @(~,~)dispAudioPlayback(app,0);
                app.speakerDPlayer.TimerPeriod = 0.3;

            end
        end

        function dispAnalysis(~,p_Axis,results,rTimeFrames,clrs,YLims)
            % Display the analysis results as filled areas:
            if ~isempty(results)
                % Find Changes:
                results = [0;results];
                chgPts_Idx = find(diff(results)~=0);
                results(1) = [];
                % Get Corresponding Colours:
                idxClrs = results(chgPts_Idx,1)+1;
%                 if results(1) == 1 && ~results(2)
%                     chgPts_Idx = [1; chgPts_Idx];
%                     idxClrs = [2; idxClrs];
%                 end
                if results(end)
                   chgPts_Idx(end+1) = size(results,1);
                   idxClrs(end+1) = 2;
                end

                % Y-Coordinates:
                if ~exist("YLims","var")
                    YLims = ylim(p_Axis);
                end
                yCoords = [YLims(1) YLims(1) ...
                    YLims(2) YLims(2)];
                for ii=1:size(chgPts_Idx,1)-1
                    if ~strcmpi(clrs{idxClrs(ii)},'white')
                        % X-Coordinates:
                        xCoords = [rTimeFrames(chgPts_Idx(ii),1) rTimeFrames(chgPts_Idx(ii+1)-1,2) ...
                            rTimeFrames(chgPts_Idx(ii+1)-1,2) rTimeFrames(chgPts_Idx(ii),1)];
                        % Plot Results:
                        p = patch(p_Axis,xCoords,yCoords,clrs{idxClrs(ii)},'FaceAlpha',0.3,'EdgeColor','none');
                        p.Annotation.LegendInformation.IconDisplayStyle = 'off';
                    end
                end
            end
            %EOF
        end

        
%         function x = waitForFuncs(~,checker)
%             time0 = tic;
%             while any(checker)
%                 if toc(time0)>15
%                     disp('Error: All functions did not end in time.');
%                     break;
%                 end
%                 pause(.5);
%             end
%             if any(checker)
%                 x = 'NotDone';
%             else
%                 x = 'Done';
%             end
%         end
        
        function disableFuncs(app,AF)
            app.AnalyseSMButton.Enable = "off";
            app.AnalyseSpeakerDiarButton.Enable = "off";
            app.AnalyseAllButton.Enable = "off";
            app.LoadFolderButton.Enable = "off";
            app.PlayButton.Enable = "off";
            app.PlayButton_SpeakD.Enable = "off";
        
            switch AF
                case "SM"
                    app.AnalyseSMButton.Enable = "on";
                case "SpeakDiar"
                    app.AnalyseSpeakerDiarButton.Enable = "on";
                case "All"
                    app.AnalyseAllButton.Enable = "on";
                case "Play"
                    app.PlayButton.Enable = "on";
                    app.PlayButton_SpeakD.Enable = "on";
            end
            %EOF
        end
        
        function enableFuncs(app)
            app.AnalyseSMButton.Enable = "on";
            app.AnalyseSpeakerDiarButton.Enable = "on";
            app.AnalyseAllButton.Enable = "on";
            app.LoadFolderButton.Enable = "on";
            app.PlayButton.Enable = "on";
            app.PlayButton_SpeakD.Enable = "on";            
        end
        
        function runSpeakerDiar(app)

            %load("/Users/kylewimmer/Documents/2021/Speech_Project/Data_Collection/Programs/APP/SpeakerDiar_Functions/speakerVars.mat",'speakSegments');
            load('SpeakerDiar_Functions/speakerVars.mat','speakSegments');
            app.speakSegments = speakSegments;
            % Analyse Speaker Diarization:
            % Analysis takes quite some time...
            app.funcExecuting(4) = 1;   % Function is executing
            app.speakerAnalysed = 0;    % SpeakerDiarisation not yet analysed

            % Check if close application has been requested:
            pause(1.5);
            if app.closeReq
                return;
            end

            % Run Speaker Diarisation:
            % tic
            % reset(app.audioDS);
            %                     [~,app.speakSegments] = speakerDiar_func(app,app.audioDS,...
            %                         app.audioFs);
            % toc
            % Check if close application has been requested:
            pause(0.25);
            if app.closeReq
                return;
            end

            % Create Masks:
            maskLabels = app.speakSegments(:,3);
            regionsVAD = app.speakSegments(:,1:2);
            app.speakMsk = signalMask(table(regionsVAD,categorical(maskLabels)));
            % Display Speaker Diarisation:
            dispSpeakerDiar(app);

            % Change Parameters:
            app.speakerAnalysed = 1;
        end
        
        function runSM(app)
                % Analyse Stress:
                app.funcExecuting(2) = 1;
                [app.HRV,app.HRV_Time,app.StressYN,app.StressTime,app.sensorDataTab.ECG,...
                    app.sensorDataTab.ecgTimestamps] = ecgStressClassi_func(...
                    app.sensorDataTab.ECG,app.sensorDataTab.ecgTimestamps);
                app.stressAnalysed = 1;
                % Plot Stress:
                dispStress(app);
                app.funcExecuting(2) = 0;
                
                % Analyse Movement:
                app.funcExecuting(3) = 1;
                [app.XYZ_Acc,app.MoveSummed_Acc,app.MoveTimeSegs_Acc,...
                    app.MoveYN_Acc] = polarMoveClassi_func(app.sensorDataTab.ACC,...
                    app.sensorDataTab.accTimestamps);
                app.moveAnalysed = 1;
                % Plot Movement:
                dispMovement(app);
                app.funcExecuting(3) = 0;
        end
        
        function getStats(app)
            if app.loadedStatus && app.stressAnalysed && app.moveAnalysed
                % Stress Stats Parameters:
                app.stressObs = cell(size(app.HRV_Time));
                app.stressMinBeats = cell(size(app.HRV));
                app.stressMaxBeats = cell(size(app.HRV));
                app.stressAvgBeats = cell(size(app.HRV_Time));
                % Movement Stats Parameters:
                app.moveObs = cell(size(app.MoveYN_Acc));
                app.moveMaxG = cell(size(app.MoveYN_Acc));
                app.moveAvgG = cell(size(app.MoveYN_Acc));
    
              % Stress Stats:
                for ii=1:size(app.HRV_Time,1)
                    % Get #OfStressObservations:
                    app.stressObs{ii} = size(app.StressYN{ii}(app.StressYN{ii}==1),1);
                    % Get Min Beats per Minute:
                    [bpmBuf,~] = buffer(app.HRV{ii},5);
                    hr = (1./mean(bpmBuf,1))*60;
                    app.stressMinBeats{ii} = round(min(hr),0);
                    % Get Max Beats per Minute:
                    app.stressMaxBeats{ii} = round(max(hr),0);
                    % Get Avg Beats per Minute:
                    app.stressAvgBeats{ii} = round(mean(hr),0);
    %                 Dur = seconds(app.HRV_Time{ii}(end)-app.HRV_Time{ii}(1));
    %                 app.stressAvgBeats{ii} = round((size(app.HRV_Time{ii},1)/double(Dur))*60,0);
                    
                    app.StatsTable.Data.(ii)(1) = app.stressObs{ii};
                    app.StatsTable.Data.(ii)(2) = app.stressMinBeats{ii};
                    app.StatsTable.Data.(ii)(3) = app.stressMaxBeats{ii};
                    app.StatsTable.Data.(ii)(4) = app.stressAvgBeats{ii};
                end
                % Movement Stats:
                for ii=1:size(app.MoveYN_Acc,1)
                    % Get #OfMovementObservations:
                    app.moveObs{ii} = size(app.MoveYN_Acc{ii}(app.MoveYN_Acc{ii}==1),1);
                    % Get Max G's:
                    app.moveMaxG{ii} = max(app.MoveSummed_Acc{ii});
                    % Get Acg G's:
                    app.moveAvgG{ii} = mean(app.MoveSummed_Acc{ii});
    
                    app.StatsTable.Data.(ii)(5) = app.moveObs{ii};
                    app.StatsTable.Data.(ii)(6) = app.moveMaxG{ii};
                    app.StatsTable.Data.(ii)(7) = app.moveAvgG{ii};
                end
            end
            if app.loadedStatus && app.speakerAnalysed
                % SpeakerDiar Stats Parameters:
                app.speakTurns = zeros(size(unique(app.speakSegments(:,3))));
                app.speakDur = cell(size(unique(app.speakSegments(:,3))));
                app.speakLongestPhrase = cell(size(unique(app.speakSegments(:,3))));
                app.speakLongestTurn = cell(size(unique(app.speakSegments(:,3))));
                gap = 1.8*app.audioFs; % 1.8 Second Buffer Zone
                % SpeakerDiar Stats:
                for ii=1:size(app.speakDur,1)
                    spokenPhrasesDur = (app.speakSegments(app.speakSegments(:,3)==ii,2)-app.speakSegments(app.speakSegments(:,3)==ii,1))./app.audioFs;
                    % Get Speech Duration:
                    app.speakDur{ii} = sum(spokenPhrasesDur);
                    % Get Longest Spoken Phrase:
                    app.speakLongestPhrase{ii} = max(spokenPhrasesDur);
                    % Get Longest Spoken Turn:
                    chgPts_Idx = find(diff(app.speakSegments(:,3))~=0);
                    turnLen = zeros(size(chgPts_Idx));
                    
                    for jj=2:size(chgPts_Idx,1)
                        if app.speakSegments(chgPts_Idx(jj),3) == ii
                            % Count Turn - Turn-taking:
                            app.speakTurns(ii) = app.speakTurns(ii)+1;
                            % Longest Spoken Turn:
                            tempReg = app.speakSegments(chgPts_Idx(jj-1)+1:chgPts_Idx(jj),:);
                            temp = find(tempReg(2:end,1)-tempReg(1:end-1,2)>=gap);
                            gap_Idx = [1;temp;size(tempReg,1)];
%                             disp(gap_Idx);
                            gapLen = zeros(size(gap_Idx,1)-1,1);
                            for kk=2:size(gap_Idx)
                                gapLen(kk-1) = sum(tempReg(gap_Idx(kk-1):gap_Idx(kk),2)-tempReg(gap_Idx(kk-1):gap_Idx(kk),1));
                            end
                            turnLen(jj,1) = max(gapLen);
                        end
                    end
                    % Last turn-taking index:
                    if app.speakSegments(end,3) == ii
                        app.speakTurns(ii) = app.speakTurns(ii)+1;
                    end
                    app.speakLongestTurn{ii} = max(turnLen)/app.audioFs;

                    app.StatsTable.Data.(ii)(8) = app.speakTurns(ii);
                    app.StatsTable.Data.(ii)(9) = app.speakDur{ii};
                    app.StatsTable.Data.(ii)(10) = app.speakLongestPhrase{ii};
                    app.StatsTable.Data.(ii)(11) = app.speakLongestTurn{ii};
                end
            end
        end
        
        function updateStatsTable(app)
                % Update Stats Table:
                strArr = app.PatientTable.Data.SpeakerID;
                app.StatsTable.ColumnName = strArr;
                PatZeros = zeros(size(app.StatsTable.RowName,1),size(strArr,1));
                Tab = table(PatZeros);
                Tab = splitvars(Tab,Tab.Properties.VariableNames,'NewVariableNames',strArr);
                app.StatsTable.Data = Tab;        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initialise Parameters:
%             p = gcp('nocreate');
%             if isempty(p)
%                 app.pool = parpool();
%             else
%                 app.pool = p;
%             end
            app.closeReq = 0;
            app.funcExecuting = zeros(5,1);
            app.loadedStatus = 0;
            app.aPreStoppedStatus = 1;
            app.aSpeakStoppedStatus = 1;
            app.stressAnalysed = 0;
            app.moveAnalysed = 0;
            app.speakerAnalysed = 0;
            app.audioStartFrom_Pre = 1;
            app.audioStartFrom_Speak = 1;

            % Setup progressUIFig:
            app.progressFig = uifigure('Name','Progress Figure',...
                'Visible','off','Position',[500 500 355 100],...
                'CloseRequestFcn','');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if any(app.funcExecuting)
                funcYN = uiconfirm(app.UIFigure,"Functions still running. "+...
                    "Do you wish to continue?","Functions still running/Close Request",...
                    "Icon","warning","DefaultOption",2,'Options',["Yes","No"]);
                if strcmpi(funcYN,'Yes')
                    delete(app.progressFig);
                    delete(app);
                end
            else
                YN = uiconfirm(app.UIFigure,"Do you want to close the app?",...
                    "Close Request","DefaultOption",1);
                if strcmpi(YN,'OK')
                    delete(app.progressFig);
                    delete(app)
                end
            end
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            if app.loadedStatus
                %% Load Confirmation:
                loadYN = uiconfirm(app.UIFigure,"All unsaved data will be lost. "+...
                    "Do you wish to continue?","Load Confirmation",...
                    "Icon","warning");
                if strcmpi(loadYN,"OK")
                    selPath = uigetdir;
                    if ~isequal(selPath,0)
                        loadFunc(app,selPath);
                    end
                end
            else
                selPath = uigetdir;
                if ~isequal(selPath,0)
                    loadFunc(app,selPath);
                end
            end            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
           % Play and Pause Audio Preview:
           % Check if SpeakerDiarisation Player is playing:
            speakerDNotPlaying = 0;
            if isempty(app.speakerDPlayer)
                speakerDNotPlaying = 1;
            elseif ~app.speakerDPlayer.isplaying
                speakerDNotPlaying = 1;
            end
            if ~isempty(app.audioPlayer) && speakerDNotPlaying
                    if app.aPreStoppedStatus
                        %% Play Audio:
                        app.aPreStoppedStatus = 0;
                        play(app.audioPlayer,app.audioStartFrom_Pre);
                    elseif app.audioPlayer.isplaying
                        % Pause:
                        pause(app.audioPlayer);
                        app.PlayButton.Text = "Resume";
                    else
                        % Resume:
                        resume(app.audioPlayer);
                    end
            else
                app.PlayButton.Value = 0;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop Audio Preview
            if ~isempty(app.audioPlayer) && ~app.aPreStoppedStatus
                %% Stop Audio:
                app.aPreStoppedStatus = 1;
                stop(app.audioPlayer)
                app.PlayButton.Text = 'Play';
                app.PlayButton.Value = 0;
                app.audioStartFrom_Pre = 1;
            end
        end

        % Button pushed function: AnalyseSMButton
        function AnalyseSMButtonPushed(app, event)
            % Analyse Stress and Movement Solely:
            if app.loadedStatus && ~app.closeReq
                % Create progress bar:
                app.progressFig.Visible = 'on';
                SMProgDlg = uiprogressdlg(app.progressFig,'Title',...
                    'Stress & Movement Analysis Running',...
                    'Message','Please Wait...','Indeterminate','on');
                drawnow
                % Run Stress and Movement Analysis:
                runSM(app);
                
                if app.stressAnalysed && app.moveAnalysed
                    % Get SM Stats:
                    getStats(app);
                    % Clear progressdlg:
                    app.progressFig.Visible = 'off';
                    close(SMProgDlg);
                    % Analysis Completed Indication:
                    uialert(app.UIFigure,"S&M Analysis Completed.",...
                        "S&M Analysis Completed","Icon","success");
                end
            end
        end

        % Value changed function: SelectGraph_MoveDD
        function SelectGraph_MoveDDValueChanged(app, event)
            if app.moveAnalysed
                dispMovement(app)
            end
        end

        % Value changed function: SelectGraph_StressDD
        function SelectGraph_StressDDValueChanged(app, event)
            if app.stressAnalysed
                dispStress(app);
            end
        end

        % Value changed function: SkipToEditField
        function SkipToEditFieldValueChanged(app, event)
            if app.loadedStatus
                value = app.SkipToEditField.Value;
                value = string(value);
                express = '(?<hours>0[0-9]|1[0-9]|2[0-3]+):(?<minutes>0[0-9]|[1-5][0-9]{0,1}+):(?<seconds>[0-5][0-9]{0,1}+)';
                timeStr = regexp(value,express,'names');
                
                if isempty(timeStr)
                    uialert(app.UIFigure,"Incorrect time syntax. E.g., hh:mm:ss",...
                        "Incorrect Skip To Time Syntax");
                else
                    % Get Audio Entered Time:
                    timeEnt = double(timeStr.hours)*60*60;
                    timeEnt = timeEnt+double(timeStr.minutes)*60;
                    timeEnt = timeEnt+double(timeStr.seconds);    
                    % Get Audio Starting Time:
                    [h,m,s] = hms(app.audioST);
                    timeST = h*60*60;
                    timeST = timeST+(m*60);
                    timeST = floor(timeST+s);
                    % Get Audio Ending Time:
                    timeEND = timeST + floor(app.audioPlayer.TotalSamples/app.audioFs);

                    % Skip Audio To:
                    if timeEnt<timeST
                        uialert(app.UIFigure,"Time entered is before "+...
                            "the start of the audio tract.","Incorrect SkipTo Time");
                    elseif timeEnt>timeEND
                        uialert(app.UIFigure,"Time entered is after "+...
                            "the ending of the audio tract.","Incorrect SkipTo Time");
                    else
                        timeSkipTo = timeEnt-timeST;
                        app.audioStartFrom_Pre = app.audioPlayer.SampleRate*timeSkipTo;
                        if ~app.audioStartFrom_Pre; app.audioStartFrom_Pre=1;end 
                        if ~app.aPreStoppedStatus; stop(app.audioPlayer);play(app.audioPlayer,app.audioStartFrom_Pre);end
                    end

                    
                end
            else
                app.SkipToEditField.Value = "hh:mm:ss";
            end
        end

        % Value changed function: AnalyseSpeakerDiarButton
        function AnalyseSpeakerDiarButtonValueChanged(app, event)
            if app.loadedStatus
                value = app.AnalyseSpeakerDiarButton.Value;
                if value && ~app.closeReq && ~any(app.funcExecuting)
                    % Disable All Other Functions:
                    disableFuncs(app,"SpeakDiar");
                    % Create progress bar:
                    app.progressFig.Visible = 'on';
                    speakerProgDlg = uiprogressdlg(app.progressFig,'Title',...
                        'Speaker Diarisation Analysis Running',...
                        'Message','This may take some time...','Indeterminate','on');
                    drawnow
                    % Run Speaker Diarisation:
                    runSpeakerDiar(app);
                    % Reset Parameters:
                    enableFuncs(app); app.progressFig.Visible = 'off';
                    close(speakerProgDlg); app.funcExecuting(4)=0; app.closeReq=0;
                    app.AnalyseSpeakerDiarButton.Value = 0;

                    if app.speakerAnalysed
                        % Get SpeakerDiar Stats:
                        getStats(app);
                        % Analysis Completed Indication:
                        uialert(app.UIFigure,"Speaker Diarisation Completed.",...
                            "Speaker Diarisation Completed","Icon","success");
                    end
                    
%                     audioPro = app.audioPro;
%                     speakSegments = app.speakSegments;
%                     uisave({'speakSegments'});

%                     disp(Segments)
%                     tempOut = tempDummy_func(app);
%                     disp(tempOut);

                elseif ~value
                    app.closeReq = 1;   % Stop Function Requested.
                    app.AnalyseSpeakerDiarButton.Value = 0;
                    enableFuncs(app);
                else
                    app.AnalyseSpeakerDiarButton.Value = 0;
                end
            else
                app.AnalyseSpeakerDiarButton.Value = 0;
            end
            
        end

        % Cell edit callback: PatientTable
        function PatientTableCellEdit(app, event)
            indices = event.Indices;
            if indices(1,2) == size(app.PatientTable.Data,2)
                strArr = strings(size(app.PatientTable.Data,1)+1,1);
                strArr(1,1) = "All";
                strArr(2:end,1) = app.PatientTable.Data.SpeakerID;
                app.SelectPat_SpeakerDD.Items = strArr;
                % Update StatsTable:
                app.StatsTable.ColumnName(indices(1,1)) = {app.PatientTable.Data.SpeakerID(indices(1,1))};
                % Update MovementPlots:
                if size(app.movePlots,1)>=indices(1,1)
                    if ~isempty(app.movePlots{indices(1,1)})
                        app.movePlots{indices(1,1)}.Title.String = app.PatientTable.Data.SpeakerID(indices(1,1));
                    end
                end
                % Update StressPlots:
                if size(app.stressPlots,1)>=indices(1,1)
                    if ~isempty(app.stressPlots{indices(1,1)})
                        app.stressPlots{indices(1,1)}.Title.String = app.PatientTable.Data.SpeakerID(indices(1,1));
                    end
                end
                % Update SpeakerDiar Plots:
                if ~isempty(app.speakTile)
                    Axes = findobj(app.speakTile,'Type','Axes');
                    legend(Axes(end,:),["Audio";app.PatientTable.Data.SpeakerID]);
                    if size(Axes,1)>1
                        if app.SelectPat_SpeakerDD.Value == indices(1,1)
                            Axes(1,:).Title.String = app.PatientTable.Data.SpeakerID(indices(1,1));
                        end
                    end
                end
            end
        end

        % Value changed function: SelectPat_SpeakerDD
        function SelectPat_SpeakerDDValueChanged(app, event)
            value = app.SelectPat_SpeakerDD.Value;
            if value == 0
                app.CutOutSilenceFromAudioCheckBox.Value = 0;
                app.CutOutSilenceFromAudioCheckBox.Enable = "off";
                dispSpeakerDiar(app);
            else
                app.CutOutSilenceFromAudioCheckBox.Enable = "on";
                dispSpeakerDiar(app);
            end
        end

        % Value changed function: PlayButton_SpeakD
        function PlayButton_SpeakDValueChanged(app, event)
            % Play and Pause Audio Speaker:
            previewNotPlaying = 0;
            if isempty(app.audioPlayer)
                previewNotPlaying = 1;
            elseif ~app.audioPlayer.isplaying
                previewNotPlaying = 1;
            end
            if ~isempty(app.speakerDPlayer) && previewNotPlaying
                if app.aSpeakStoppedStatus
                    %% Play Audio:
                    app.aSpeakStoppedStatus = 0;
                    play(app.speakerDPlayer,app.audioStartFrom_Speak);
                elseif app.speakerDPlayer.isplaying
                    %% Pause Audio:
                    pause(app.speakerDPlayer);
                    app.PlayButton_SpeakD.Text = "Resume";
                else
                    %% Resume Audio:
                    resume(app.speakerDPlayer);
                end
            else
                app.PlayButton_SpeakD.Value = 0;
            end
        end

        % Button pushed function: StopButton_SpeakD
        function StopButton_SpeakDPushed(app, event)
            % Stop Audio Preview
            if ~isempty(app.speakerDPlayer) && ~app.aSpeakStoppedStatus
                %% Stop Audio:
                app.aSpeakStoppedStatus = 1;
                stop(app.speakerDPlayer)
                app.PlayButton_SpeakD.Text = 'Play';
                app.PlayButton_SpeakD.Value = 0;
                app.audioStartFrom_Speak = 1;
            end
        end

        % Value changed function: SkipToEditField_SpeakD
        function SkipToEditField_SpeakDValueChanged(app, event)
            if ~isempty(app.speakerDPlayer)
                value = app.SkipToEditField_SpeakD.Value;
                value = string(value);
                express = '(?<hours>0[0-9]|1[0-9]|2[0-3]+):(?<minutes>0[0-9]|[1-5][0-9]{0,1}+):(?<seconds>[0-5][0-9]{0,1}+)';
                timeStr = regexp(value,express,'names');
                if isempty(timeStr)
                    uialert(app.UIFigure,"Incorrect time syntax. E.g., hh:mm:ss",...
                        "Incorrect Skip To Time Syntax");
                else
                    % Get Audio Entered Time:
                    timeEnt = double(timeStr.hours)*60*60;
                    timeEnt = timeEnt+double(timeStr.minutes)*60;
                    timeEnt = timeEnt+double(timeStr.seconds);    
                    % Get Audio Starting Time:
                    [h,m,s] = hms(app.audioST);
                    timeST = h*60*60;
                    timeST = timeST+(m*60);
                    timeST = floor(timeST+s);
                    % Get Audio Ending Time:
                    timeEND = timeST + floor(app.aInfo.TotalSamples/app.audioFs);

                    % Skip Audio To:
                    if timeEnt<timeST
                        uialert(app.UIFigure,"Time entered is before "+...
                            "the start of the audio tract.","Incorrect SkipTo Time");
                    elseif timeEnt>timeEND
                        uialert(app.UIFigure,"Time entered is after "+...
                            "the ending of the audio tract.","Incorrect SkipTo Time");
                    else
                        sampleSkipTo = (timeEnt-timeST)*app.audioFs;
                        if app.CutOutSilenceFromAudioCheckBox.Value
                            % Find closest element to SkipTo Value:
                            bmsk = binmask(app.speakMsk,numel(app.audioT_Comp));
                            soundIdx = find(bmsk(:,app.SelectPat_SpeakerDD.Value));
                            [~,sampleSkipTo] = min(abs(soundIdx-(sampleSkipTo)));
%                             closestVal = soundIdx(closestIdx);  
                        end
                        if sampleSkipTo~=1; sampleSkipTo = sampleSkipTo-1;end
                        app.audioStartFrom_Speak = sampleSkipTo;
                        if ~app.audioStartFrom_Speak; app.audioStartFrom_Speak=1;end 
                        if ~app.aSpeakStoppedStatus; stop(app.speakerDPlayer);play(app.speakerDPlayer,app.audioStartFrom_Speak);end
                        
                    end          
                end
            else
                app.SkipToEditField.Value = "hh:mm:ss";
            end
        end

        % Value changed function: CutOutSilenceFromAudioCheckBox
        function CutOutSilenceFromAudioCheckBoxValueChanged(app, event)
            value = app.CutOutSilenceFromAudioCheckBox.Value;
            % Recreate Speaker Diarisation Audio Player:
            reset(app.audioDS);
            audioData = read(app.audioDS);
            bmsk = binmask(app.speakMsk,numel(app.audioT_Comp));
            if value
                audioData(~bmsk(:,app.SelectPat_SpeakerDD.Value)) = [];
            end
            app.speakerDPlayer = audioplayer(audioData,app.audioFs); 
            % Create SpeakerDiar AudioPlayer Callbacks:
            app.speakerDPlayer.StartFcn = @(~,~)audioStarted(app,0);
            app.speakerDPlayer.StopFcn = @(~,~)audioStopped(app);
            app.speakerDPlayer.TimerFcn = @(~,~)dispAudioPlayback(app,0);
            app.speakerDPlayer.TimerPeriod = 0.3;
        end

        % Button pushed function: AddToTable
        function AddToTableButtonPushed(app, event)
            % Add 'Patients' to PatientTable:
            if ~isempty(app.PatientTable.Data)
                newEntry = [{"-"},{"-"},{"-"},{string(size(app.PatientTable.Data,1)+101)}];
                newEntryTab = cell2table(newEntry);
                newEntryTab.Properties.VariableNames = app.PatientTable.Data.Properties.VariableNames;
                app.PatientTable.Data = [app.PatientTable.Data;newEntryTab];
                % Update patient select DropDowns:
                strArr = strings(size(app.PatientTable.Data,1)+1,1);
                strArr(1,1) = "All";
                strArr(2:end,1) = app.PatientTable.Data.SpeakerID;
                app.SelectPat_SpeakerDD.Items = strArr;
                app.SelectPat_SpeakerDD.ItemsData = (0:size(app.PatientTable.Data,1))';
                % Update StatsTable:
                updateStatsTable(app);
            end
        end

        % Button pushed function: RemoveFromTable
        function RemoveFromTableButtonPushed(app, event)
            % Remove 'Patients' From PatientTable:
            if ~isempty(app.PatientTable.Data)
                selectedRow = app.PatientTable.Selection;
                if any(string(app.PatientTable.Data.PatientID(selectedRow))~="-")
                    uialert(app.UIFigure,"Can't remove initial patient(s).",...
                        "Trying to Remove Initial Patient(s)");
                else
                    YN = uiconfirm(app.UIFigure,"Are you sure you want to remove "+...
                        "the selected row(s)?","Patient Remove Confirmation",...
                        "Options",["Yes";"No"]);
                    if strcmp(YN,"Yes")
                        app.PatientTable.Data(selectedRow,:) = [];
                        % Update patient select DropDowns:
                        strArr = strings(size(app.PatientTable.Data,1)+1,1);
                        strArr(1,1) = "All";
                        strArr(2:end,1) = app.PatientTable.Data.SpeakerID;
                        app.SelectPat_SpeakerDD.Items = strArr;
                        app.SelectPat_SpeakerDD.ItemsData = (0:size(app.PatientTable.Data,1))';
                        % Update StatsTable:
                        updateStatsTable(app);
                    end
                end
            end         
        end

        % Value changed function: AnalyseAllButton
        function AnalyseAllButtonValueChanged(app, event)
            value = app.AnalyseAllButton.Value;
            % Run All:
            if app.loadedStatus
                if value && ~app.closeReq && ~any(app.funcExecuting)
                    % Disable All Functions:
                    disableFuncs(app,"All")
                    % Create progress bar:
                    app.progressFig.Visible = 'on';
                    AllProgDlg = uiprogressdlg(app.progressFig,'Title',...
                        'Analyse All Running','Message',...
                        'Running Stress and Movement Analysis.',...
                        'Indeterminate','on');
                    drawnow
                    % Run Stress and Movement Analise:
                    runSM(app);
                    if app.stressAnalysed && app.moveAnalysed
                        % Adjust Progress Message:
                        AllProgDlg.Message = "Running Speaker Diarisation. "...
                            + "This may take some time...";
                        % Run Speaker Diarisation:
                        runSpeakerDiar(app);
                        % Reset Parameters:
                        enableFuncs(app); app.progressFig.Visible = 'off';
                        close(AllProgDlg); app.funcExecuting(4)=0; app.closeReq=0;
                        if app.speakerAnalysed
                            % Get All Stats:
                            getStats(app);
                            % Analysis Completed Indication:
                            uialert(app.UIFigure,"Analise Completed.",...
                                "Analise Completed","Icon","success");
                        else
                            uialert(app.UIFigure,"Speaker Diarisation could not finish.",...
                                "Speaker Diarisation Failed: Analyse All");
                        end
                    else
                        % Reset Parameters:
                        enableFuncs(app); app.progressFig.Visible = 'off';
                        close(AllProgDlg); app.funcExecuting(4)=0; app.closeReq=0;

                        uialert(app.UIFigure,"S&M Analise could not finish.",...
                            "S&M Failed: Analyse All");
                    end
                elseif ~value
                    app.closeReq = 1;   % Stop Function Requested.
                    app.AnalyseAllButton.Value = 0;
                    enableFuncs(app);
                end
            end
            app.AnalyseAllButton.Value = 0;
        end

        % Button pushed function: UpdateStatsButton
        function UpdateStatsButtonPushed(app, event)
            % Update Stats in Overview Tab:
            getStats(app);
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            % Export All Figures and Stats Table to a Single PDF file:
            filter = {'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                % Create progress bar:
                app.progressFig.Visible = 'on';
                ExportProgDlg = uiprogressdlg(app.progressFig,'Title',...
                    'Exporting Analyses',...
                    'Message','Please Wait...','Indeterminate','on');
                drawnow
                exportapp(app.UIFigure,[filepath filename]);
                % Export all speakerDiar results:
                for ii=1:size(app.SelectPat_SpeakerDD.Items,2)
                    app.SelectPat_SpeakerDD.Value = app.SelectPat_SpeakerDD.ItemsData(ii);
                    try
                        dispSpeakerDiar(app);
                        exportgraphics(app.speakTile,[filepath filename],"Append",true);
                    catch ME
                        disp(ME);
                        break;
                    end
                end
                % Export all stress results:
                for ii=1:size(app.SelectGraph_StressDD.Items,2)
                    app.SelectGraph_StressDD.Value = app.SelectGraph_StressDD.Items{ii};
                    dispStress(app);
                    for jj=1:size(app.stressPlots,1)
                        try
                            exportgraphics(app.stressPlots{jj},[filepath filename],"Append",true);
                        catch ME
                            disp(ME);
                            break;
                        end
                    end
                end
                % Export all movement results:
                for ii=1:size(app.SelectGraph_MoveDD.Items,2)
                    app.SelectGraph_MoveDD.Value = app.SelectGraph_MoveDD.Items{ii};
                    dispMovement(app);
                    for jj=1:size(app.movePlots,1)
                        try
                            exportgraphics(app.movePlots{jj},[filepath filename],"Append",true);
                        catch ME
                            disp(ME);
                            break;
                        end
                    end
                end

                app.progressFig.Visible = "off";
                close(ExportProgDlg);
            end
%             
%             exportgraphics(app.StatsPanel,"TempExport.pdf","Append",true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'E';
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.GridLayout = uigridlayout(app.LoadTab);
            app.C2KAnalyserLabel = uilabel(app.GridLayout);
            app.C2KAnalyserLabel.Text = 'C2K - Analyser';
            app.GridLayoutPolar = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayoutPolar);
            app.AnalyseSMButton = uibutton(app.GridLayout4, 'push');
            app.AnalyseSMButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseSMButtonPushed, true);
            app.AnalyseSMButton.Tooltip = {'Solely Analyse Stress & Movement'};
            app.AnalyseSMButton.Text = 'Analyse S&M';
            app.AnalyseSpeakerDiarButton = uibutton(app.GridLayout4, 'state');
            app.AnalyseSpeakerDiarButton.ValueChangedFcn = createCallbackFcn(app, @AnalyseSpeakerDiarButtonValueChanged, true);
            app.AnalyseSpeakerDiarButton.Tooltip = {'Solely Analyse Speaker Diarisation'};
            app.AnalyseSpeakerDiarButton.Text = 'Analyse SpeakerDiar';
            app.AnalyseAllButton = uibutton(app.GridLayout4, 'state');
            app.AnalyseAllButton.ValueChangedFcn = createCallbackFcn(app, @AnalyseAllButtonValueChanged, true);
            app.AnalyseAllButton.Tooltip = {'Analyse All'};
            app.AnalyseAllButton.Text = 'Analyse All';
            app.GridLayout5 = uigridlayout(app.GridLayoutPolar);
            app.DirExampleImage = uiimage(app.GridLayout5);
            app.DirExampleImage.ImageSource = fullfile(pathToMLAPP, 'Directory_Selection_Example.png');
            app.FolderSelectionExampleLabel = uilabel(app.GridLayout5);
            app.FolderSelectionExampleLabel.Text = 'Folder Selection Example';
            app.LoadFolderButton = uibutton(app.GridLayout5, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AudioPreviewAxes = uiaxes(app.GridLayout2);
            title(app.AudioPreviewAxes, 'Audio Preview')
            app.AudioPreviewAxes.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.GridLayout3, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.SkipToEditFieldLabel = uilabel(app.GridLayout3);
            app.SkipToEditFieldLabel.Text = 'Skip To:';
            app.SkipToEditField = uieditfield(app.GridLayout3, 'text');
            app.SkipToEditField.ValueChangedFcn = createCallbackFcn(app, @SkipToEditFieldValueChanged, true);
            app.SkipToEditField.Value = 'hh:mm:ss';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.PatientTable = uitable(app.GridLayout6);
            app.PatientTable.ColumnName = {'PatientID'; 'Gender'; 'Age'; 'SpeakerID'};
            app.PatientTable.RowName = {};
            app.PatientTable.ColumnSortable = [true true true true false];
            app.PatientTable.SelectionType = 'row';
            app.PatientTable.ColumnEditable = [false false false true];
            app.PatientTable.CellEditCallback = createCallbackFcn(app, @PatientTableCellEdit, true);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.AddToTable = uibutton(app.GridLayout7, 'push');
            app.AddToTable.ButtonPushedFcn = createCallbackFcn(app, @AddToTableButtonPushed, true);
            app.AddToTable.Text = 'Add';
            app.RemoveFromTable = uibutton(app.GridLayout7, 'push');
            app.RemoveFromTable.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromTableButtonPushed, true);
            app.RemoveFromTable.Text = 'Remove';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.TabGroup2 = uitabgroup(app.AnalysisTab);
            app.OverviewTab = uitab(app.TabGroup2);
            app.OverviewTab.Title = 'Overview';
            app.GridOverview = uigridlayout(app.OverviewTab);
            app.GridLayout8 = uigridlayout(app.GridOverview);
            app.UpdateStatsButton = uibutton(app.GridLayout8, 'push');
            app.UpdateStatsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStatsButtonPushed, true);
            app.UpdateStatsButton.Tooltip = {'Update Stats Table'};
            app.UpdateStatsButton.Text = 'Update Stats';
            app.ExportResultsButton = uibutton(app.GridLayout8, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Tooltip = {'Export Analyses To PDF'};
            app.ExportResultsButton.Text = 'Export Results';
            app.StatsPanel = uipanel(app.GridOverview);
            app.GridLayout9 = uigridlayout(app.StatsPanel);
            app.StatsTable = uitable(app.GridLayout9);
            app.StatsTable.ColumnName = {'Patient1'; 'Patient2'; 'Patient3'};
            app.StatsTable.RowName = {'Stress: #OfObs'; 'Stress: Min bpm'; 'Stress: Max bpm'; 'Stress: Avg bpm'; 'Move: #OfObs'; 'Move: Max G''s'; 'Move: Avg G''s'; 'Speak: TurnTaking [obs]'; 'Speak: SpokenDur [sec]'; 'Speak: LongestPhrase [sec]'; 'Speak: LongestSpokenTurn [sec]'};
            app.StressTab = uitab(app.TabGroup2);
            app.StressTab.Title = 'Stress';
            app.GridStressAnal = uigridlayout(app.StressTab);
            app.GridLayout_StressDD = uigridlayout(app.GridStressAnal);
            app.SelectGraphLabel_2 = uilabel(app.GridLayout_StressDD);
            app.SelectGraphLabel_2.Text = 'Select Graph:';
            app.SelectGraph_StressDD = uidropdown(app.GridLayout_StressDD);
            app.SelectGraph_StressDD.Items = {'ECG (Raw)', 'HRV (RR-Intervals)'};
            app.SelectGraph_StressDD.ValueChangedFcn = createCallbackFcn(app, @SelectGraph_StressDDValueChanged, true);
            app.SelectGraph_StressDD.Value = 'ECG (Raw)';
            app.StressPanel = uipanel(app.GridStressAnal);
            app.MovementTab = uitab(app.TabGroup2);
            app.MovementTab.Title = 'Movement';
            app.GridMovementAnal = uigridlayout(app.MovementTab);
            app.GridLayout_MoveDD = uigridlayout(app.GridMovementAnal);
            app.SelectGraphLabel = uilabel(app.GridLayout_MoveDD);
            app.SelectGraphLabel.Text = 'Select Graph:';
            app.SelectGraph_MoveDD = uidropdown(app.GridLayout_MoveDD);
            app.SelectGraph_MoveDD.Items = {'Triaxial (XYZ)', 'TotalMovement (Summed)'};
            app.SelectGraph_MoveDD.ValueChangedFcn = createCallbackFcn(app, @SelectGraph_MoveDDValueChanged, true);
            app.SelectGraph_MoveDD.Value = 'Triaxial (XYZ)';
            app.MovementPanel = uipanel(app.GridMovementAnal);
            app.SpeakerDiarTab = uitab(app.TabGroup2);
            app.SpeakerDiarTab.Title = 'SpeakerDiar';
            app.GridSpeakerAnal = uigridlayout(app.SpeakerDiarTab);
            app.GridLayout_SpeakerDD = uigridlayout(app.GridSpeakerAnal);
            app.SelectGraphLabel_3 = uilabel(app.GridLayout_SpeakerDD);
            app.SelectGraphLabel_3.Text = 'Select Patient:';
            app.SelectPat_SpeakerDD = uidropdown(app.GridLayout_SpeakerDD);
            app.SelectPat_SpeakerDD.Items = {'All'};
            app.SelectPat_SpeakerDD.ItemsData = 0;
            app.SelectPat_SpeakerDD.ValueChangedFcn = createCallbackFcn(app, @SelectPat_SpeakerDDValueChanged, true);
            app.SelectPat_SpeakerDD.Value = 0;
            app.CutOutSilenceFromAudioCheckBox = uicheckbox(app.GridLayout_SpeakerDD);
            app.CutOutSilenceFromAudioCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutOutSilenceFromAudioCheckBoxValueChanged, true);
            app.CutOutSilenceFromAudioCheckBox.Enable = 'off';
            app.CutOutSilenceFromAudioCheckBox.Text = 'CutOutSilenceFromAudio';
            app.GridSpeakDButtons = uigridlayout(app.GridSpeakerAnal);
            app.PlayButton_SpeakD = uibutton(app.GridSpeakDButtons, 'state');
            app.PlayButton_SpeakD.ValueChangedFcn = createCallbackFcn(app, @PlayButton_SpeakDValueChanged, true);
            app.PlayButton_SpeakD.Text = 'Play';
            app.StopButton_SpeakD = uibutton(app.GridSpeakDButtons, 'push');
            app.StopButton_SpeakD.ButtonPushedFcn = createCallbackFcn(app, @StopButton_SpeakDPushed, true);
            app.StopButton_SpeakD.Text = 'Stop';
            app.SkipToLabel = uilabel(app.GridSpeakDButtons);
            app.SkipToLabel.Text = 'Skip To:';
            app.SkipToEditField_SpeakD = uieditfield(app.GridSpeakDButtons, 'text');
            app.SkipToEditField_SpeakD.ValueChangedFcn = createCallbackFcn(app, @SkipToEditField_SpeakDValueChanged, true);
            app.SkipToEditField_SpeakD.Value = 'hh:mm:ss';
            app.SpeakD_DispPanel = uipanel(app.GridSpeakerAnal);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C2K_SessionAnalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end