classdef C2K_SessionRecorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        PatientTable               matlab.ui.control.Table
        GridLayout4                matlab.ui.container.GridLayout
        NoDevicesFoundLabel        matlab.ui.control.Label
        SearchingLabel             matlab.ui.control.Label
        BLEOptTree_Polar           matlab.ui.container.CheckBoxTree
        PolarConnectionsNode       matlab.ui.container.TreeNode
        BLE2Node                   matlab.ui.container.TreeNode
        BLE3Node                   matlab.ui.container.TreeNode
        GridLayout5                matlab.ui.container.GridLayout
        DisconnectButton           matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        SearchButton               matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout9                matlab.ui.container.GridLayout
        DirLabel                   matlab.ui.control.Label
        GetDirButton               matlab.ui.control.Button
        SessionNameEditField       matlab.ui.control.EditField
        SessionNameEditFieldLabel  matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        GridLayout7                matlab.ui.container.GridLayout
        ShowButton                 matlab.ui.control.StateButton
        GridLayout8                matlab.ui.container.GridLayout
        RecordingLamp              matlab.ui.control.Lamp
        RecordingLampLabel         matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        RecordingButton            matlab.ui.control.StateButton
        GridLayout3                matlab.ui.container.GridLayout
        PreviewButton              matlab.ui.control.StateButton
        PreviewDropDown            matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        C2K_RecorderLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Polar BLE Comms:
        polarBLEList % Polar Connection Option List (Table)
        polarBLEConns % Connected Polar Devices (cell - BLE Objects)
        polarBLEChars % Characteristics to Connected Polar Devices (cell - characteristics)
        polarBLEDescrip % Descriptors to Connected Polar Devices (cell - descriptors)

        % Polar Sensor Parameters:
        ecgPara     % Polar ECG Parameters - Struct - SR, Res
        accPara     % Polar ACC Parameters - Struct - SR, Res, Rng
        ecgScope    % ECG display - timescope
        accScope    % ACC display - timescope
        ecgWriter   % ECG FileWriter - binaryFileWriter
        accWriter   % ACC FileWriter - binaryFileWriter
        ecgTBWriter % ECG TimeBegin FileWriter - binaryFileWriter
        accTBWriter % ACC TimeBegin FileWriter - binaryFileWriter

        % Audio Parameters:
        audioSR         % Audio Sampling Rate
        audionBits      % Audio Number of Bits
        audionChannels  % Audio Number of Channels
        audioRecorder   % audioRecorder (type - audiorecorder)
        audioScope      % Audio Display - timescope
        audioTime       % Audio Starting Time

        % App Status
        streamingStatus logical
        recordingStatus logical
        loadedData logical
    end
    
    methods (Access = private)
        
        function setupBLEComms(app,devNames)
            %% Prereq: Service and Charateristics:
            % We want to collect ECG and Acceleration from the Polar devices (H10).
            % These features are within a Custom service, 
            % UUID: 'FB005C80-02E7-F387-1CAD-8ACD2D8DF0C8',
            % Under the Characteristic, UUID: 'FB005C82-O2E7-F387-1CAD-8ACD2D8DF0C8'.
            PMD_Service = "FB005C80-02E7-F387-1CAD-8ACD2D8DF0C8";
            PMD_CntrlPnt = "FB005C81-02E7-F387-1CAD-8ACD2D8DF0C8";
            PMD_DataMTU_Char = "FB005C82-02E7-F387-1CAD-8ACD2D8DF0C8";
            
            BLEConnNames = strings(size(app.polarBLEConns));
            for ii=1:size(BLEConnNames,1)
                BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
            end
            connTo = find(strcmpi(BLEConnNames(:),devNames));

            app.polarBLEChars = cell(size(connTo,1),2); % Characteristics list
            app.polarBLEDescrip = cell(size(connTo,1),2); % Descriptors
            for ii=1:size(connTo,1)
                app.polarBLEChars{ii,1} = characteristic(app.polarBLEConns{connTo(ii)},PMD_Service,PMD_CntrlPnt);
                app.polarBLEDescrip{ii,1} = descriptor(app.polarBLEChars{ii,1},"Client Characteristic Configuration");
                app.polarBLEChars{ii,2} = characteristic(app.polarBLEConns{connTo(ii)},PMD_Service,PMD_DataMTU_Char);
                app.polarBLEDescrip{ii,2} = descriptor(app.polarBLEChars{ii,2},"Client Characteristic Configuration");
            end

            %% Prereq: Enable PMD Control Point indication and PMD Data MTU notifications
            for ii=1:size(connTo,1)
                app.polarBLEChars{ii,1}.subscribe
                app.polarBLEChars{ii,2}.subscribe
            end
            disp('subscribed')

            %% Create TimeScopes for all Sensors:
            app.ecgScope = cell(size(connTo));
            app.accScope = cell(size(connTo));
            for ii=1:size(app.ecgScope)
                % ECG Scope
                title = BLEConnNames(connTo(ii)) + "_ECG";
                app.ecgScope{ii} = timescope("SampleRate",app.ecgPara.SR,...
                "YLimits",[-2.5e3 2.5e3],"TimeSpanSource","property","TimeSpan",20,...
                "Title",title);
                app.ecgScope{ii}.hide;
                % ACC Scope
                title = BLEConnNames(connTo(ii))+"_ACC";
                app.accScope{ii} = timescope("SampleRate",app.accPara.SR,...
                "YLimits",[-1e3 1e3],"TimeSpanSource","property","TimeSpan",20,...
                "Title",title);
                app.accScope{ii}.hide;
            end

            %% Create FileWriters for all Sensors:
            app.ecgWriter = cell(size(connTo));
            app.ecgTBWriter = cell(size(connTo));
            app.accWriter = cell(size(connTo));
            app.accTBWriter = cell(size(connTo));
            % Create filename:
            filePath = pwd + "/";
%             filePath = matlab.desktop.editor.getActiveFilename;
%             filePath = extractBefore(filePath,find(filePath=='/',1,'last'))+"/";

            for ii=1:size(app.ecgWriter)
                % ECG FileWriters:
                fname = filePath+"ecg"+BLEConnNames(connTo(ii))+".bin";
                app.ecgWriter{ii} = dsp.BinaryFileWriter(fname);
                fname = filePath+"ecgtimeBegin"+BLEConnNames(connTo(ii))+".bin";
                app.ecgTBWriter{ii} = dsp.BinaryFileWriter(fname);
                % ACC FileWriters:
                fname = filePath+"acc"+BLEConnNames(connTo(ii))+".bin";
                app.accWriter{ii} = dsp.BinaryFileWriter(fname);
                fname = filePath+"acctimeBegin"+BLEConnNames(connTo(ii))+".bin";
                app.accTBWriter{ii} = dsp.BinaryFileWriter(fname);                
            end

        end
        
        function startStreaming(app,devNames)
            %% Start Stream:
            % Start Stream ECG/ACC command:
            start_ECG = [02 00 00 01 app.ecgPara.SR 00 01 01 app.ecgPara.Res 00]; % Start ECG Stream with the 
            % following measurement_settings: SampleRate=130Hz, Resolution=14bits
            start_ACC = [02 02 00 01 app.accPara.SR 00 01 01 app.accPara.Res 00 02 01 app.accPara.Rng 00]; % Start ACC Stream 
            % with the following measurement_settings: SampleRate=50Hz,
            % Resolution=16bits, Range=2G.
            
            BLEConnNames = strings(size(app.polarBLEConns));
            for ii=1:size(BLEConnNames,1)
                BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
            end
            connTo = find(strcmpi(BLEConnNames,devNames));


%             % Check DataAvailableFcn:
%             for ii=1:size(connTo,1)
%                 if isempty(app.polarBLEChars{connTo(ii),2}.DataAvailableFcn)
%                     app.polarBLEChars{ii,2}.DataAvailableFcn = @(src, event)displayCharacteristicData(app,src, event, [],[],app.ecgScope,app.accScope,[],[],1/app.ecgPara.SR,1/app.accPara.SR,connTo(ii),1);
%                 end
%             end

            % Initialise Start Stream:
            for ii=1:size(connTo,1)
                % Write to PMD Control Point:
                app.polarBLEChars{connTo(ii),1}.write(start_ECG);
                app.polarBLEChars{connTo(ii),1}.write(start_ACC);
            end
            disp('Streaming...')
            % Set streamingStatus:
            app.streamingStatus = true;
        %EOF
        end
        
        function stopStreaming(app,devNames)
            % Stop ECG/ACC Stream Command:
            stop_ECG = [03 00]; % Stop ECG Command
            stop_ACC = [03 02]; % Stop ACC Command

            BLEConnNames = strings(size(app.polarBLEConns));
            for ii=1:size(BLEConnNames,1)
                BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
            end
            connTo = find(strcmpi(BLEConnNames,devNames));

            for ii=1:size(connTo,1)
                app.polarBLEChars{connTo(ii),1}.write(stop_ECG);
                app.polarBLEChars{connTo(ii),1}.write(stop_ACC);
                app.polarBLEChars{connTo(ii),2}.DataAvailableFcn = [];
            end
            disp('Stopped')
            % Clear streamingStatus:
            app.streamingStatus = false;
        %EOF
        end
        
        % Sensor dataAvailable Function:
        function displayCharacteristicData(app,src,~,dEcgSR,dAccSR,dID,preYN)
            % Read data:
            [data,~] = read(src,'oldest');
            TimeStamps = getVal(app,data(2:9));
            
            % Process data:
            switch data(1)
                case 0  % ECG Data
                    % ECG Processing:
                    % ECG has only one frame type, '0'. FrameSize=3Bytes.
                    dECG = zeros((size(data,2)-10)/3,3);
                    dECG(:,1) = data(11:3:end); dECG(:,2) = data(12:3:end);
                    dECG(:,3) = data(13:3:end);
                    ecg = getVal(app,dECG);
                    ECGTimestamps = TimeStamps:dEcgSR*1e9:((length(ecg)-1)*1e9*dEcgSR)+TimeStamps;

                    if ~preYN
                        app.ecgWriter{dID}([ECGTimestamps',ecg]);
                        app.ecgScope{dID}(ecg);
                        % Get starting time:
                        if ~isfile(app.ecgTBWriter{dID}.Filename)
                            curTime = datetime('now','Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                            curTime = double(convertTo(curTime,"epochtime","Epoch","2000-01-01","TicksPerSecond",1e9));
                            app.ecgTBWriter{dID}(curTime);
                        end
                    else 
                        % Merely Preview
                        app.ecgScope{dID}(ecg);
                    end
            
                case 2  % ACC Data
                    frameType = data(10);
                    switch frameType
                        case 0
                            % 3-Bytes size: 3x8bits (3->x,y,x; and each uses a byte)
                            X = zeros((size(data,2)-10)/3,1); Y = X; Z = X;
                            X(:,1) = shex2dec(dec2hex(data(11:3:end)));
                            Y(:,1) = shex2dec(dec2hex(data(12:3:end)));
                            Z(:,1) = shex2dec(dec2hex(data(13:3:end)));
                        case 1
                            % 6-Bytes size: (3x2x8bits) -> Each (x,y,z) uses 2Bytes
                            dX = zeros((size(data,2)-10)/6,2); dY = dX; dZ = dX;
                            dX(:,1) = data(11:6:end); dX(:,2) = data(12:6:end);
                            dY(:,1) = data(13:6:end); dY(:,2) = data(14:6:end);
                            dZ(:,1) = data(15:6:end); dZ(:,2) = data(16:6:end);
                            X = getVal(app,dX); Y = getVal(app,dY); Z = getVal(app,dZ);
                        case 2
                            % 9-Bytes size: (3x3x8bits) -> Each uses 3Bytes
                            dX = zeros((size(data,2)-10)/9,3); dY = dX; dZ = dX;
                            dX(:,1) = data(11:9:end); dX(:,2) = data(12:9:end); dX(:,3) = data(13:9:end);
                            dY(:,1) = data(14:9:end); dY(:,2) = data(15:9:end); dY(:,3) = data(16:9:end);
                            dZ(:,1) = data(17:9:end); dZ(:,2) = data(18:9:end); dZ(:,3) = data(19:9:end);
                            X = getVal(app,dX); Y = getVal(app,dY); Z = getVal(app,dZ);
                    end
                    % ACC Processing:
                    ACCTimestamps = TimeStamps:dAccSR*1e9:((length(X)-1)*1e9*dAccSR)+TimeStamps;
                    
                    if ~preYN
                        app.accWriter{dID}([ACCTimestamps',X,Y,Z]);
                        app.accScope{dID}([X, Y, Z]);
                        % Get starting time:
                        if ~isfile(app.accTBWriter{dID}.Filename)
                            curTime = datetime('now','Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                            curTime = double(convertTo(curTime,"epochtime","Epoch","2000-01-01","TicksPerSecond",1e9));
                            app.accTBWriter{dID}(curTime);
                        end
                    else
                        % Merely Preview:
                        app.accScope{dID}([X, Y, Z]);
                    end
            end
            %EOF
        end

        function val = getVal(~,data)
            valHex = string(zeros(size(data)));
            for ii=1:size(valHex,2)
                valHex(:,ii) = string(dec2hex(data(:,end+1-ii),2));
            end
            val = shex2dec(char(join(valHex,'',2))); % shex2dec - for negative values.
        %EOF
        end
        
        function disconnectFunc(app)
            % Disconnect BLE Devices:
            if ~isempty(app.polarBLEConns)
                app.polarBLEConns = '';
                app.polarBLEChars = '';
                app.polarBLEDescrip = '';
            end
            % Enable Load:
            app.LoadButton.Enable = "on";
        %EOF
        end
        
        function changePatientTab(app,devNames)
            % Delete Previous PatientTable
            delete(app.PatientTable);   
            % Create Temperary Patient Data:
            dID = cell(size(devNames));
            pID = dID;
            genderDropDown = dID;
            age = dID;

            for ii=1:size(devNames,1)
                dID{ii} = char(devNames(ii));
                pID{ii} = ii;
                genderDropDown{ii} = 'Choose';
                age{ii} = 0;
            end

            pData = [dID,pID,genderDropDown,age];

            app.PatientTable = uitable(app.GridLayout,...
                "ColumnName",{'DeviceID'; 'PatientID'; 'Gender'; 'Age'},...
                "ColumnFormat",{'char','char',{'Male','Female'},'numeric'},...
                "ColumnEditable",[false,true,true,true],...
                "ColumnSortable",[true,true,false,true],...
                "Data",pData);
            app.PatientTable.Layout.Row = 2;
            app.PatientTable.Layout.Column = 1;
        
        end
        
        function DisSucc = waitForDisconnect(app)
            DisSucc = 0;
            time0 = tic;
            timelimit = 8;
            while true
                if toc(time0)>timelimit % Waiting limit
                    uialert(app.UIFigure,"Did not disconnect in time.",...
                        "Disconnect Error");
                    break;
                end
                if ~app.streamingStatus % Done Recording/Streaming
                    DisSucc = 1;
                    break; 
                end  
            end 
         %EOF
        end
        
        function [ecgData,accData,ecgTB,accTB] = getSensorData(app)
            % Read sensor data from binary files:

            % Create filename:
            filePath = pwd + "/";
%             filePath = matlab.desktop.editor.getActiveFilename;
%             filePath = extractBefore(filePath,find(filePath=='/',1,'last'))+"/";

            % Get device names:
            BLEConnNames = strings(size(app.polarBLEConns));
            for ii=1:size(BLEConnNames,1)
                BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
            end

            % Get Starting Time and TimeStamps of the Data Streams:
            ecgTB = cell(size(app.polarBLEConns));
            accTB = cell(size(app.polarBLEConns));

            for ii=1:size(app.polarBLEConns,1)
                % FileIDs:
                fname = filePath+"ecgtimeBegin"+BLEConnNames(ii)+".bin";
                fileID_ECGTimeBegin = fopen(fname,"r");
                fname = filePath+"acctimeBegin"+BLEConnNames(ii)+".bin";
                fileID_ACCTimeBegin = fopen(fname,"r");
                % Read Files:
                ecgTB{ii} = fread(fileID_ECGTimeBegin,Inf,"double"); % Get Starting Time
                accTB{ii} = fread(fileID_ACCTimeBegin,Inf,"double"); % Get Starting Time
            end
            % Close Files:
            fclose('all');
            
            % Get ECG/ACC Data:
            ecgData = cell(size(app.polarBLEConns));
            accData = cell(size(app.polarBLEConns));
            for ii=1:size(app.polarBLEConns,1)
                % FileIDs:
                fname = filePath+"ecg"+BLEConnNames(ii)+".bin";
                fileID_ECG = fopen(fname,"r");
                fname = filePath+"acc"+BLEConnNames(ii)+".bin";
                fileID_ACC = fopen(fname,"r");
                % Read Files:
                ecgData{ii} = fread(fileID_ECG,Inf,'double');
                accData{ii} = fread(fileID_ACC,Inf,'double');
            end
            % Close Files:
            fclose("all");
        %EOF    
        end
        
        function [ECG,ACC,ecgTimeStamps,accTimeStamps] = preProcessData_func(app,ecgData,accData,ecgTimeBegin,accTimeBegin)
            % Pre-process Data:
            % Get ECG/ACC Values and Corresponding Timestamps:
            ecgTimeStamps = cell(size(app.polarBLEConns));
            accTimeStamps = cell(size(app.polarBLEConns));
            ECG = cell(size(app.polarBLEConns));
            ACC = cell(size(app.polarBLEConns));

            for ii=1:size(accTimeStamps,1)
                if accTimeBegin{ii}<ecgTimeBegin{ii}
                    timeBegin = accTimeBegin{ii};
                else
                    timeBegin = ecgTimeBegin{ii};
                end
                % ECG:
                timeDiff = timeBegin-ecgData{ii}(1);
                ecgTimeStamps{ii} = datetime((ecgData{ii}(1:2:end)+timeDiff)/1e9,'ConvertFrom','epochtime','Epoch','2000-01-01',...
                    'Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                ECG{ii} = ecgData{ii}(2:2:end);

                % ACC:
                timeDiff = timeBegin-accData{ii}(1);
                accTimeStamps{ii} = datetime((accData{ii}(1:4:end)+timeDiff)/1e9,'ConvertFrom','epochtime','Epoch','2000-01-01',...
                    'Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                X = accData{ii}(2:4:end);
                Y = accData{ii}(3:4:end);
                Z = accData{ii}(4:4:end);
                ACC{ii} = [X,Y,Z];
            end
        %EOF
        end

        function [equiSamples,equiTime] = getEquiData(~,samples,timestamps,Fs)
            % getEquiData() finds gaps/delays/interruptions in the data streams and
            % fills them with NaN. This ensures an evenly spaced time-series for
            % analysis (Stress and movement detection). It also finds timestamps that
            % occur faster than 1/Fs and interpolates the series to the appropriate
            % timestamp.

            % Get Equidistant Samples and Timestamps:
            equiSamples = cell(size(samples));
            equiTime = cell(size(samples));

            for cellCnt=1:size(equiSamples,1)
                timeDiff = diff(timestamps{cellCnt}); % Get time-intervals
                equiDistant = (round(seconds(timeDiff*Fs),1)); % Create equidistant spacing (time)
                gapIdx = find(equiDistant>2); % Find Gaps
                % Determine Equidistant size:
                equiSize = floor(seconds(timestamps{cellCnt}(end)-timestamps{cellCnt}(1))*Fs)+1;
                equiSamples{cellCnt} = zeros(equiSize,size(samples{cellCnt},2)); % Initialise equidistant samples

                % If no gaps in recording:
                if isempty(gapIdx)
                    nsTime = double(convertTo(timestamps{cellCnt},"epochtime","Epoch","2000-01-01","TicksPerSecond",1e9));
                    nsEquiTime = (nsTime(1):1e9/Fs:nsTime(1)+1e9*(equiSize-1)/Fs)';
                    equiTime{cellCnt} = datetime(nsEquiTime/1e9,'ConvertFrom','epochtime','Epoch','2000-01-01',...
                        'Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                    % Get Equidistant Samples: Interpolation (Modified Cubic)
                    for ii=1:size(samples{cellCnt},2)
                        equiSamples{cellCnt}(:,ii) = makima(nsTime,samples{cellCnt}(:,ii),nsEquiTime);
                    end
                else % If gaps(communication delay larger than 1/Fs)
                    % Convert Timestamps to nanoSeconds:
                    nsTime = double(convertTo(timestamps{cellCnt},"epochtime","Epoch","2000-01-01","TicksPerSecond",1e9));
                    % Get Equidistant Timestamps:
                    nsEquiTime = (nsTime(1):1e9/Fs:nsTime(1)+(1e9*(equiSize-1))/Fs)';

                    % Get Gap Sizes:
                    gapSizes = zeros(size(gapIdx));
                    for ii=1:size(gapSizes,1)
                        gapSizes(ii) = length(nsTime(gapIdx(ii)):1e9/Fs:nsTime(gapIdx(ii)+1))-1;
                    end

                    % Get Equidistant Samples: Interpolation (Modified Cubic)
                    for ii=1:size(samples{cellCnt},2)
                        equiSamples{cellCnt}(1:gapIdx(1),ii) = ...
                            makima(nsTime(1:gapIdx(1)),samples{cellCnt}(1:gapIdx(1),ii),...
                            nsEquiTime(1:gapIdx(1))); % First section

                        gapCnt = sum(gapSizes(1:end-1)); % gap counter - which timestamp is missing

                        equiSamples{cellCnt}(gapIdx(end)+(gapCnt+1)+gapSizes(end):end,ii) = ...
                            makima(nsTime(gapIdx(end)+1:end),...
                            samples{cellCnt}(gapIdx(end)+1:end,ii),...
                            nsEquiTime(gapIdx(end)+(gapCnt+1)+gapSizes(end):end)); % Last section
                        equiSamples{cellCnt}(gapIdx(end)+(gapCnt+1):gapIdx(end)+(gapCnt)+gapSizes(end),ii) = NaN;
                    end

                    for ii=1:size(gapIdx,1)-1 % Middle sections
                        for jj=1:size(samples{cellCnt},2)
                            gapCnt = sum(gapSizes(1:ii-1));
                            equiSamples{cellCnt}(gapIdx(ii)+(gapCnt+1)+gapSizes(ii):gapIdx(ii+1)+gapSizes(ii)+gapCnt,jj) = ...
                                makima(nsTime(gapIdx(ii)+1:gapIdx(ii+1)),...
                                samples{cellCnt}(gapIdx(ii)+1:gapIdx(ii+1),jj),...
                                nsEquiTime(gapIdx(ii)+(gapCnt+1)+gapSizes(ii):gapIdx(ii+1)+gapSizes(ii)+gapCnt));
                            equiSamples{cellCnt}(gapIdx(ii)+gapCnt+1:gapIdx(ii)+gapCnt+gapSizes(ii),jj) = NaN;
                        end
                    end
                    % From nanoSeconds EquiTime to datetime EquiTime:
                    equiTime{cellCnt} = datetime(nsEquiTime/1e9,'ConvertFrom','epochtime','Epoch','2000-01-01',...
                        'Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                end
            end
            %EOF
        end
        
        function dispAudio(app)
            % Display audio recording on timescope:
            y = getaudiodata(app.audioRecorder);
            app.audioScope(y(end-(app.audioSR*app.audioRecorder.TimerPeriod)+1:end));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App Parameters:
            app.streamingStatus = false; % Initially not streaming
            app.recordingStatus = false; % Intially not recording
            % Add Sensor Parameters:
            app.ecgPara.SR = 130; % ECG - 130 Hz sample rate
            app.ecgPara.Res = 14; % ECG - 14bit resolution
            app.accPara.SR = 50;  % ACC - 50 Hz sample rate
            app.accPara.Res = 16; % ACC - 16bit resolution
            app.accPara.Rng = 2;  % ACC - ±2G range
            % Add Audio Parameters:
            app.audioSR = 16e3;     % Audio - 16 kHz sampling rate
            app.audionBits = 16;    % Audio - number of bits (will be ignored, dependend on recording device)
            app.audionChannels = 1; % Audio - number of channels
            app.audioRecorder = audiorecorder(app.audioSR,app.audionBits,app.audionChannels); % Audio - Initialise

            % Setup audio display:
            app.audioRecorder.TimerFcn = @(~,~)dispAudio(app);
            app.audioRecorder.TimerPeriod = 50e-3;      % Audio - Display audio rate

            app.audioScope = timescope("SampleRate",app.audioSR,...
                "YLimits",[-1 1],"TimeSpanSource","property","TimeSpan",20,...
                "Title","AudioScope");
            app.audioScope.hide;
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,"Do you want to close the app","Close Request");
            if strcmpi(YN,'OK')
                % Delete temporary files:
                delete *Polar*.bin;
                disconnectFunc(app);
                close(app.audioScope);
                for ii=1:size(app.ecgScope,1)
                    close(app.accScope{ii});
                    close(app.ecgScope{ii});
                end
                delete(app)
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % Clear previous connections:
            app.polarBLEList = '';
            app.polarBLEConns = '';
            app.polarBLEChars = '';
            app.polarBLEDescrip = '';

            % Hide labels within Tree box:
            app.NoDevicesFoundLabel.Visible = "off";
            % Delete previous tree:
            delete(app.BLEOptTree_Polar); 
            % Recreate tree:
            app.BLEOptTree_Polar = uitree(app.GridLayout4, 'checkbox');
            app.BLEOptTree_Polar.Layout.Row = 1;
            app.BLEOptTree_Polar.Layout.Column = 2;

            % Searching Appearance:
            app.BLEOptTree_Polar.Enable = 'off';
            app.SearchingLabel.Visible = "on";
            
            % Search for Polar devices: (BLE)
            app.polarBLEList = blelist("Name","Polar","Timeout",8);
          
            % Display Search Results:
            app.SearchingLabel.Visible = "off";
            if ~isempty(app.polarBLEList)
                app.BLEOptTree_Polar.Enable = 'on';
                % Display Polar devices in PolarOptTree:
                % Add new nodes:
                devNames = app.polarBLEList.Name;
                for ii=1:size(devNames,1)
                    uitreenode(app.BLEOptTree_Polar,"Text",devNames(ii));
                end
            else
                app.BLEOptTree_Polar.Enable = 'off';
                app.NoDevicesFoundLabel.Visible = "on";
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Change App status:
            app.loadedData = false; % Data in patientTable is no longer loaded data
            % Get selected nodes/devices:
            selectedDevs = app.BLEOptTree_Polar.CheckedNodes;
            if app.streamingStatus
                % Alert Message:
                uialert(app.UIFigure,...
                    "Can't connect while recording/streaming. Stop recording first then try again.",...
                    "Trying to Connect While Recording","Icon","info");
            
            elseif ~isempty(selectedDevs) % Connect to checked Polar Devices:
                connectFlag = 1;
                if ~isempty(app.PatientTable.Data)
                    connectFlag = 0;
                   YN = uiconfirm(app.UIFigure,"All unsaved data will be lost. Do you wish to continue?",...
                        "Reset Connections","Options",{'Yes','No'},"DefaultOption",2,...
                        "Icon","warning");
                   if strcmpi(YN,'Yes')
                       connectFlag = 1;
                       % Delete previous temporary sensor recordings:
                       delete *Polar*.bin;
                   end
                end
%                 % Reset Connections:
%                 if ~isempty(app.polarBLEConns)
%                     app.polarBLEConns = '';
%                 end
                if connectFlag
                    % Disable Load:
                    app.LoadButton.Enable = "off";
                    % Connect:
                    app.polarBLEConns = cell(size(selectedDevs));
                    for ii=1:size(selectedDevs,1)
                        app.polarBLEConns{ii} = ble(selectedDevs(ii).Text);
                    end
                    disp('connected');
                    BLEConnNames = strings(size(selectedDevs));
                    for ii=1:size(selectedDevs,1)
                        BLEConnNames(ii) = selectedDevs(ii).Text;
                    end
                    setupBLEComms(app,BLEConnNames);
               
                    % Change PreviewDropDown:
                    items_dropDownList = strings(size(selectedDevs));
                    for ii=1:size(selectedDevs,1)
                        items_dropDownList(ii) = selectedDevs(ii).Text;
                    end
                    app.PreviewDropDown.Items = items_dropDownList;
                    app.PreviewDropDown.Enable = "on";
                    app.PreviewDropDown.FontAngle = "normal";
    
                    % Change PatientTable:
                    changePatientTab(app,items_dropDownList);
                end
            else
                app.PreviewDropDown.Items = "No Connections";
                app.PatientTable.Data = [];
                disconnectFunc(app);
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % Disconnect from all connected devices:
            if app.streamingStatus % Alert - Disconnect while recording
                % Alert Message:
                uialert(app.UIFigure,...
                    "Can't disconnect while recording/streaming. Stop recording first then try again.",...
                    "Trying to Disconnect While Recording","Icon","info");
            
            elseif ~isempty(app.polarBLEConns) % Disconnect
                % Confirm Disconnect:
                msg = "Disconnect from BLE Devices will result in the lost of unsaved data. Are you sure you want to disconnect?";
                YN = uiconfirm(app.UIFigure,msg,"Disconnect?","Icon","warning",...
                    "Options",{'Yes','No'},"DefaultOption",2);
                if strcmpi(YN,'Yes')
                    % Reset Parameters:
                    app.PreviewDropDown.Items = "No Connections";
                    app.PreviewDropDown.Enable = "off";
                    app.PatientTable.Data = [];
                    disconnectFunc(app);
                    % Delete temporary sensor recordings:
                    delete *Polar*.bin;
                end
                % Enable ConnectButton:
                app.ConnectButton.Enable = "on";
            end
        end

        % Value changed function: PreviewButton
        function PreviewButtonValueChanged(app, event)
            value = app.PreviewButton.Value;
            % Preview Connected BLE Devices Sensor Data:
            if value
                app.PreviewDropDown.Enable = "off";
                if ~isempty(app.polarBLEConns) 
                    % Get Preview Device's Name:
                    prev_val = app.PreviewDropDown.Value;
                    BLEConnNames = strings(size(app.polarBLEConns));
                    for ii=1:size(BLEConnNames,1)
                        BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
                    end
                    connTo = find(strcmpi(BLEConnNames(:),prev_val));

                    % Setup DataAvailableFcn for Preview Option:
                    for ii=1:size(connTo,1)
                        app.polarBLEChars{connTo(ii),2}.DataAvailableFcn = @(src, event)displayCharacteristicData(app,src,event,1/app.ecgPara.SR,1/app.accPara.SR,connTo(ii),1);
                    end
                    % Start Preview Streaming:
                    startStreaming(app,prev_val);
                    app.RecordingButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.ecgScope{connTo}.show;
                    app.accScope{connTo}.show;
                else 
                    app.PreviewButton.Value = 0;
                    app.PreviewDropDown.Enable = "on";
                end
            else
                app.PreviewDropDown.Enable = "on";
                app.RecordingButton.Enable = "on";
                app.StopButton.Enable = "on";
                if ~isempty(app.polarBLEConns) 
                    % Get Preview Device's Name:
                    prev_val = app.PreviewDropDown.Value;
                    % Stop Streaming:
                    stopStreaming(app,prev_val);
                    waitForDisconnect(app);
                    for ii=1:size(app.ecgScope)
                        release(app.ecgScope{ii});
                        release(app.accScope{ii});
                    end
                end 
            end
        end

        % Value changed function: RecordingButton
        function RecordingButtonValueChanged(app, event)
            % Start and Pause Session Recording:
            disp('Record');

            if ~isempty(app.polarBLEConns)
                value = app.RecordingButton.Value;

                BLEConnNames = strings(size(app.polarBLEConns));
                for ii=1:size(BLEConnNames,1)
                    BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
                end

                if value % Start Recording
                    % Visual Changes:
                    app.RecordingLamp.Color = [0 1 0];
                    app.RecordingButton.Text = "Pause";

                    % Setup DataAvailableFcn for Record Option:
                    for ii=1:size(BLEConnNames,1)
                        app.polarBLEChars{ii,2}.DataAvailableFcn = @(src, event)displayCharacteristicData(app,src,event,1/app.ecgPara.SR,1/app.accPara.SR,ii,0);
                    end
                    % Start Streaming:
                    startStreaming(app,BLEConnNames);
                    % Disable PreviewButton:
                    app.PreviewButton.Enable = "off";
                    % Disable ConnectButton:
                    app.ConnectButton.Enable = "off";
                    % Start Audio Recording:
                    if ~app.recordingStatus
                        record(app.audioRecorder);
                        app.audioTime = datetime('now','Format','yyyy.MM.dd HH:mm:ss.SSSSSSSSS');
                        app.recordingStatus = true;
                        % Delete previous tempory files:
                        delete *Polar*.bin;
                        % Disable Save Button:
                        app.SaveButton.Enable = "off";
                    else
                        resume(app.audioRecorder);
                    end


                else % Pause Recording
                    % Visual Changes:
                    app.RecordingLamp.Color = [1 0.6 0];
                    app.RecordingButton.Text = "Resume";

                    % Pause Streaming:
                    stopStreaming(app,BLEConnNames);
                    % Pause Audio Recording:
                    pause(app.audioRecorder);
                end
            else
                app.RecordingButton.Value = 0;
            end
        end

        % Value changed function: ShowButton
        function ShowButtonValueChanged(app, event)
            prevVal = app.ShowButton.Value;
            if ~isempty(app.polarBLEConns)
                if prevVal% Show scopes
                    for ii=1:size(app.ecgScope,1)
                        app.ecgScope{ii}.show
                        app.accScope{ii}.show

                        release(app.ecgScope{ii});
                        release(app.accScope{ii});
                    end
                    app.audioScope.show
                    release(app.audioScope);

                else    % Hide scopes
                    for ii=1:size(app.ecgScope,1)
                        app.ecgScope{ii}.hide
                        app.accScope{ii}.hide
                    end
                    app.audioScope.hide
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop Session:
            if ~isempty(app.polarBLEConns)
                % Visual Changes:
                app.RecordingLamp.Color = [1 0 0];
                app.RecordingButton.Text = "Start";
                app.RecordingButton.Value = 0;
                % Stop Streaming:
                BLEConnNames = strings(size(app.polarBLEConns));
                for ii=1:size(BLEConnNames,1)
                    BLEConnNames(ii) = app.polarBLEConns{ii}.Name;
                end
                stopStreaming(app,BLEConnNames);
                % Stop Audio Recording:
                stop(app.audioRecorder);
                app.recordingStatus = false;


                % Enable PreviewButton:
                app.PreviewButton.Enable = "on";

                % Release Objects:
                time0 = tic;
                timelimit = 10; % Five-second timelimit
                % Wait untill all devices has receive stop command:
                while true % Ensure streaming has stopped
                    if toc(time0)>timelimit
                        % Error:
                        uialert(app.UIFigure,"All of the BLE devices did not stop recording",...
                            "Release Error");
                        break;
                    end
                    if isempty(app.polarBLEChars{end,2}.DataAvailableFcn)
                        % Release Objects:
                        for ii=1:size(app.ecgWriter)
                            release(app.ecgWriter{ii});
                            release(app.ecgTBWriter{ii});
                            release(app.accWriter{ii});
                            release(app.accTBWriter{ii});
                        end
                        disp('Stop Done');
                        break;
                    end
                end
                % Enable Save Button:
                app.SaveButton.Enable = "on";
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Create and Save Polar Sensor Data and Patient Information:
            if app.recordingStatus
                uialert(app.UIFigure,"Still recording. Stop recording first.",...
                    "Save Error: Still Recording","Icon","info");
            %% Loaded Data: Save merely changes made to patient information
            elseif app.loadedData
                % Save Patient Table as .csv:
                writetable(app.PatientTable.Data,app.SessionNameEditField.Value+"_PatientInfo.csv",...
                    'Delimiter',',');
                uialert(app.UIFigure,"Patient Data Updated.",...
                    "Updated","Icon","success");
            elseif ~isempty(dir('*.bin'))
                %% Check Session Info:
                if isempty(app.SessionNameEditField.Value)
                    uialert(app.UIFigure,"Error. Enter Session Name.",...
                        "Error:No Session Name");
                else
                %% Sync Sensor Data:
                    % Retrieve Sensor Data:
                    [ecgData,accData,ecgTB,accTB] = getSensorData(app);
                    [ECG,ACC,ecgTimeStamps,accTimeStamps] = preProcessData_func(app,ecgData,accData,ecgTB,accTB);
                    % Delete binary files:
                    delete *Polar*.bin;
                    %% Equidistant ECG/ACC Data:
                    [ECG,ecgTimestamps] = getEquiData(app,ECG,ecgTimeStamps,app.ecgPara.SR);
                    [ACC,accTimestamps] = getEquiData(app,ACC,accTimeStamps,app.accPara.SR);
    
                    %% Save Variables:
                    % Save Directory:
                    if isempty(app.DirLabel.Text)
                        fileDir = app.SessionNameEditField.Value;
                    else
                        fileDir = app.DirLabel.Text + "/" +...
                            app.SessionNameEditField.Value;
                    end

                    % Parameters:
                    patientTab = cell2table(app.PatientTable.Data,"VariableNames",app.PatientTable.ColumnName(:));
                    pID = patientTab.PatientID;
                    dataTab = table(pID,ECG,ecgTimestamps,ACC,accTimestamps);
                    % Save Sensor Data (Cell-type):
                    save(fileDir+"_DataTab.mat","dataTab");
                    % Save Patient Table as .csv:
                    writetable(patientTab,fileDir+"_PatientInfo.csv",...
                        'Delimiter',',');
                    uialert(app.UIFigure,"Data Saved.","Saved","Icon","success");
                    % Save Audio Data as .wav:
                    audiowrite(fileDir+"_Audio.wav",...
                        getaudiodata(app.audioRecorder),app.audioSR);
                    audioSTime = app.audioTime;
                    save(fileDir+"_audioSTime.mat","audioSTime");

                    %% Enable Buttons:
                    % Enable re-Recording:
                    app.ConnectButton.Enable = "on";

                end
                
            %% Error: No recorded data
            else
                uialert(app.UIFigure,"No data recorded. First record.",...
                    "Save Error: No Recorded Data","Icon","info");
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile("*.csv");
            if ~isequal(file,0)
                % File Selected - Read
                app.loadedData = true;
                t = fullfile(path,file);
                app.PatientTable.Data = readtable(t);
            end
        end

        % Button pushed function: GetDirButton
        function GetDirButtonPushed(app, event)
            selPath = uigetdir;
            app.DirLabel.Text = selPath;
            disp(app.DirLabel.Text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'E';
            app.ExitMenu.Text = 'Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.C2K_RecorderLabel = uilabel(app.GridLayout);
            app.C2K_RecorderLabel.Text = 'C2K_Recorder';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.PreviewDropDown = uidropdown(app.GridLayout3);
            app.PreviewDropDown.Items = {'Preview Connected Devices...'};
            app.PreviewDropDown.Enable = 'off';
            app.PreviewDropDown.Value = 'Preview Connected Devices...';
            app.PreviewButton = uibutton(app.GridLayout3, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.PreviewButton.Text = 'Preview';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.RecordingButton = uibutton(app.GridLayout7, 'state');
            app.RecordingButton.ValueChangedFcn = createCallbackFcn(app, @RecordingButtonValueChanged, true);
            app.RecordingButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout7, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.RecordingLampLabel = uilabel(app.GridLayout8);
            app.RecordingLampLabel.Text = 'Recording:';
            app.RecordingLamp = uilamp(app.GridLayout8);
            app.ShowButton = uibutton(app.GridLayout7, 'state');
            app.ShowButton.ValueChangedFcn = createCallbackFcn(app, @ShowButtonValueChanged, true);
            app.ShowButton.Text = 'Show';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout9, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.GridLayout9, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SessionNameEditFieldLabel = uilabel(app.GridLayout9);
            app.SessionNameEditFieldLabel.Text = 'Session Name:';
            app.SessionNameEditField = uieditfield(app.GridLayout9, 'text');
            app.SessionNameEditField.CharacterLimits = [0 35];
            app.GetDirButton = uibutton(app.GridLayout9, 'push');
            app.GetDirButton.ButtonPushedFcn = createCallbackFcn(app, @GetDirButtonPushed, true);
            app.GetDirButton.Text = 'GetDir';
            app.DirLabel = uilabel(app.GridLayout9);
            app.DirLabel.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.SearchButton = uibutton(app.GridLayout5, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ConnectButton = uibutton(app.GridLayout5, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.GridLayout5, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.BLEOptTree_Polar = uitree(app.GridLayout4, 'checkbox');
            app.BLEOptTree_Polar.Enable = 'off';
            app.PolarConnectionsNode = uitreenode(app.BLEOptTree_Polar);
            app.PolarConnectionsNode.Text = 'Polar Connections...';
            app.BLE2Node = uitreenode(app.BLEOptTree_Polar);
            app.BLE2Node.Text = 'BLE2';
            app.BLE3Node = uitreenode(app.BLEOptTree_Polar);
            app.BLE3Node.Text = 'BLE3';
            app.SearchingLabel = uilabel(app.GridLayout4);
            app.SearchingLabel.Visible = 'off';
            app.SearchingLabel.Text = 'Searching...';
            app.NoDevicesFoundLabel = uilabel(app.GridLayout4);
            app.NoDevicesFoundLabel.Visible = 'off';
            app.NoDevicesFoundLabel.Text = '   No Devices Found...';
            app.PatientTable = uitable(app.GridLayout);
            app.PatientTable.ColumnName = {'DeviceID'; 'PatientID'; 'Gender'; 'Age'};
            app.PatientTable.RowName = {};
            app.PatientTable.ColumnSortable = [true true false true];
            app.PatientTable.ColumnEditable = [false true true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C2K_SessionRecorder

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end